classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICProUIFigure           matlab.ui.Figure
        ICProMenu               matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        PreferencesMenu         matlab.ui.container.Menu
        GuideMenu               matlab.ui.container.Menu
        BatchMenu               matlab.ui.container.Menu
        SaveBatchMenu           matlab.ui.container.Menu
        SaveAsBatchMenu         matlab.ui.container.Menu
        LoadBatchMenu           matlab.ui.container.Menu
        DeleteBatchMenu         matlab.ui.container.Menu
        ClearBatchMenu          matlab.ui.container.Menu
        ExportMenu              matlab.ui.container.Menu
        IndResExp               matlab.ui.container.Menu
        TEIndRes                matlab.ui.container.Menu
        IDLRplots               matlab.ui.container.Menu
        IDexport                matlab.ui.container.Menu
        DCexport                matlab.ui.container.Menu
        BCexport                matlab.ui.container.Menu
        OCexport                matlab.ui.container.Menu
        SCexport                matlab.ui.container.Menu
        QCexport                matlab.ui.container.Menu
        RCexport                matlab.ui.container.Menu
        Rawexport               matlab.ui.container.Menu
        ThUIndRes               matlab.ui.container.Menu
        ThUIDExp                matlab.ui.container.Menu
        SamRatioExp             matlab.ui.container.Menu
        NatURatioExp            matlab.ui.container.Menu
        TEsheet                 matlab.ui.container.Menu
        ThUsheet                matlab.ui.container.Menu
        TEThUsheet              matlab.ui.container.Menu
        Label_BIDThU            matlab.ui.control.Label
        Field_BIDThU            matlab.ui.control.EditField
        Field_RawFolder         matlab.ui.control.EditField
        Button_RawFolder        matlab.ui.control.Button
        Label_SIDThU            matlab.ui.control.Label
        Field_SIDThU            matlab.ui.control.EditField
        Panel_InputOutput       matlab.ui.container.TabGroup
        TabGroup_IDinput        matlab.ui.container.Tab
        Tabs_IDinput            matlab.ui.container.TabGroup
        Tab_IDWeights           matlab.ui.container.Tab
        Label_SampleList        matlab.ui.control.Button
        Field_SampleList        matlab.ui.control.TextArea
        Label_USC               matlab.ui.control.Label
        Field_USC               matlab.ui.control.TextArea
        Label_ThSC              matlab.ui.control.Label
        Label_UBC               matlab.ui.control.Label
        Field_ThSC              matlab.ui.control.TextArea
        Field_UBC               matlab.ui.control.TextArea
        Label_ThBC              matlab.ui.control.Label
        Label_400ul             matlab.ui.control.Label
        Label_10ml              matlab.ui.control.Label
        Label_4ml               matlab.ui.control.Label
        Field_ThBC              matlab.ui.control.TextArea
        Field_400ul             matlab.ui.control.TextArea
        Field_10ml              matlab.ui.control.TextArea
        Field_4ml               matlab.ui.control.TextArea
        Label_Sediment          matlab.ui.control.Label
        Field_Sediment          matlab.ui.control.TextArea
        Tab_RawIntensities      matlab.ui.container.Tab
        Footnote2               matlab.ui.control.Label
        Field_cps238U_R         matlab.ui.control.TextArea
        Field_cps236U_R         matlab.ui.control.TextArea
        Field_cps232Th_R        matlab.ui.control.TextArea
        Field_cps230Th_R        matlab.ui.control.TextArea
        Label_cps238U_R         matlab.ui.control.Label
        Label_cps236U_R         matlab.ui.control.Label
        Label_cps232Th_R        matlab.ui.control.Label
        Label_cps230Th_R        matlab.ui.control.Label
        Label_cps238U           matlab.ui.control.Label
        Label_cps236U           matlab.ui.control.Label
        Label_cps232Th          matlab.ui.control.Label
        Field_cps238U           matlab.ui.control.TextArea
        Field_cps236U           matlab.ui.control.TextArea
        Field_cps232Th          matlab.ui.control.TextArea
        Label_cps230Th          matlab.ui.control.Label
        Field_cps230Th          matlab.ui.control.TextArea
        Label_cpsSampleList     matlab.ui.control.Label
        Field_cpsSampleList     matlab.ui.control.TextArea
        Tab_TEoutput            matlab.ui.container.Tab
        TabGroup_TEoutput       matlab.ui.container.TabGroup
        Tab_TEresults           matlab.ui.container.Tab
        Table_TEresults         matlab.ui.control.Table
        DataSelTE               matlab.ui.container.ButtonGroup
        CUSelTE                 matlab.ui.container.ButtonGroup
        ErrpTE                  matlab.ui.control.RadioButton
        ErrTE                   matlab.ui.control.RadioButton
        ConcTE                  matlab.ui.control.RadioButton
        IDButton                matlab.ui.control.ToggleButton
        DCButton                matlab.ui.control.ToggleButton
        BCButton                matlab.ui.control.ToggleButton
        OCButton                matlab.ui.control.ToggleButton
        SCButton                matlab.ui.control.ToggleButton
        QCButton                matlab.ui.control.ToggleButton
        RCButton                matlab.ui.control.ToggleButton
        RawButton               matlab.ui.control.ToggleButton
        Tab_TEplot              matlab.ui.container.Tab
        ThIDFig                 matlab.ui.control.UIAxes
        UIDFig                  matlab.ui.control.UIAxes
        Tab_ThUoutput           matlab.ui.container.Tab
        TabGroup_ThUoutput      matlab.ui.container.TabGroup
        Tab_ThUresults          matlab.ui.container.Tab
        ThUBCcheck              matlab.ui.control.CheckBox
        CUSelThU                matlab.ui.container.ButtonGroup
        ErrpThU                 matlab.ui.control.RadioButton
        ErrThU                  matlab.ui.control.RadioButton
        ConcThU                 matlab.ui.control.RadioButton
        DataSelThU              matlab.ui.container.ButtonGroup
        ThUIDButton             matlab.ui.control.ToggleButton
        RatioNButton            matlab.ui.control.ToggleButton
        RatioSButton            matlab.ui.control.ToggleButton
        Table_ThUresults        matlab.ui.control.Table
        SpikeRecoveryTab        matlab.ui.container.Tab
        RecoveryFig             matlab.ui.control.UIAxes
        SpikesPanel             matlab.ui.container.Panel
        IsoAmountRatio          matlab.ui.container.Panel
        RYLabel1                matlab.ui.control.Label
        RYLabel2                matlab.ui.control.Label
        RYLabel3                matlab.ui.control.Label
        RYLabel4                matlab.ui.control.Label
        Field_RYb236            matlab.ui.control.NumericEditField
        Field_RY236             matlab.ui.control.NumericEditField
        Field_RY230             matlab.ui.control.NumericEditField
        Field_RY229             matlab.ui.control.NumericEditField
        SpikeConcentration      matlab.ui.container.Panel
        Field_CY236             matlab.ui.control.NumericEditField
        Field_CY230             matlab.ui.control.NumericEditField
        Field_CY229             matlab.ui.control.NumericEditField
        SpikeSelection          matlab.ui.container.Panel
        DropDown_229            matlab.ui.control.DropDown
        ThspikeDropDownLabel    matlab.ui.control.Label
        DropDown_230            matlab.ui.control.DropDown
        ThspikeDropDown_2Label  matlab.ui.control.Label
        DropDown_236            matlab.ui.control.DropDown
        UspikeDropDownLabel     matlab.ui.control.Label
        Button_Delete236        matlab.ui.control.Button
        Button_Delete230        matlab.ui.control.Button
        Button_Delete229        matlab.ui.control.Button
        Button_EdtSve236        matlab.ui.control.Button
        Button_EdtSve230        matlab.ui.control.Button
        Button_EdtSve229        matlab.ui.control.Button
        Button_Run              matlab.ui.control.Button
        Button_ThUtoggle        matlab.ui.control.StateButton
        Button_TEtoggle         matlab.ui.control.StateButton
        Panel_ThU               matlab.ui.container.Panel
        Label_QCID              matlab.ui.control.Label
        Field_QCID              matlab.ui.control.EditField
        Label_RinseID           matlab.ui.control.Label
        Field_RinseID           matlab.ui.control.EditField
        Label_SpikeID           matlab.ui.control.Label
        Field_SpikeID           matlab.ui.control.EditField
        Label_ICPNatUID         matlab.ui.control.Label
        Field_ICPNatUID         matlab.ui.control.EditField
        Label_ICPBlankID        matlab.ui.control.Label
        Field_ICPBlankID        matlab.ui.control.EditField
        Label_NSamples          matlab.ui.control.Label
        Label_NBlocks           matlab.ui.control.Label
        Field_NSamples          matlab.ui.control.EditField
        Field_NBlocks           matlab.ui.control.EditField
        Panel_AnalysisInfo      matlab.ui.container.Panel
        Panel_TECorr            matlab.ui.container.Panel
        Check_DC                matlab.ui.control.CheckBox
        Check_BC                matlab.ui.control.CheckBox
        Check_OC                matlab.ui.control.CheckBox
        Check_SC                matlab.ui.control.CheckBox
        Check_QC                matlab.ui.control.CheckBox
        Check_RC                matlab.ui.control.CheckBox
        AnalysisSelection       matlab.ui.container.ButtonGroup
        Toggle_TE               matlab.ui.control.ToggleButton
        Toggle_ThU              matlab.ui.control.ToggleButton
        Label_BlankID           matlab.ui.control.Label
        Label_SampleID          matlab.ui.control.Label
        Field_BlankID           matlab.ui.control.EditField
        Field_SampleID          matlab.ui.control.EditField
        Field_RawDataPath       matlab.ui.control.EditField
        Button_RawDataPath      matlab.ui.control.Button
    end


    properties (Access = private)
        AppPath = dir([mfilename('fullpath'),'.mlapp']);    % Description
        Output = struct;                                    % Processing input
        Input = struct;                                     % Processing output
        BatchName = 'unsaved batch'                         % Batch name
        SMatch = struct;                                    % TE and Th/U sample match up
    end

    methods (Access = private)
        function IN = getInput(app)
            %---------------------------------------------------------------------------------
            % Function: Get input parameters
            % This function copies all input parameters and saves them locally as a batch
            % file.
            %% -------------------------------------------------------------------------------

            % Analysis toggle
            app.Input.AnalysisToggle = app.AnalysisSelection.SelectedObject.Tag;

            % Analysis information
            if strcmp(app.Input.AnalysisToggle, 'TEtoggled')
                app.Input.TEpar.RawPath = app.Field_RawDataPath.Value;                              % Raw file directory
                app.Input.TEpar.SID = app.Field_SampleID.Value;                                    % Sample ID
                app.Input.TEpar.pBID = app.Field_BlankID.Value;                                  % Blank ID
            elseif strcmp(app.Input.AnalysisToggle, 'ThUtoggled')
                app.Input.ThUpar.RawPath = app.Field_RawDataPath.Value;                             % Raw file directory
                app.Input.ThUpar.SID = app.Field_SampleID.Value;                                   % Sample ID
                app.Input.ThUpar.pBID = app.Field_BlankID.Value;                                 % Blank ID
            end
            
            % TE corrections
            app.Input.TEpar.CCheck.RC = app.Check_RC.Value;                                 % Rinse correction selection
            app.Input.TEpar.CCheck.QC = app.Check_QC.Value;                                 % QC correction selection
            app.Input.TEpar.CCheck.SC = app.Check_SC.Value;                                 % Spike correction selection
            app.Input.TEpar.CCheck.OC = app.Check_OC.Value;                                 % Oxide correction selection
            app.Input.TEpar.CCheck.BC = app.Check_BC.Value;                                 % Blank correction selection
            app.Input.TEpar.CCheck.DC = app.Check_DC.Value;                                 % Dilution correction selection
            
            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE
            % TE input
            app.Input.TEpar.VID = app.Field_SpikeID.Value;                                  % Spike ID
            app.Input.TEpar.RID = app.Field_RinseID.Value;                                  % Rinse ID
            app.Input.TEpar.QID = app.Field_QCID.Value;                                     % QC ID
            
            % Th/U input
            if ~isempty(app.Field_NBlocks.Value)                                            % # of analysis blocks
                app.Input.ThUpar.NBlocks = str2double(app.Field_NBlocks.Value);
            else
                app.Input.ThUpar.NBlocks = [];
            end
            iNSamples = regexp(app.Field_NSamples.Value,'\d');                              % # of samples per block
            if ~isempty(iNSamples)
                app.Input.ThUpar.NSamples = NaN(1,length(iNSamples));
                for i = 1 : length(iNSamples)
                    app.Input.ThUpar.NSamples(i) = str2double(app.Field_NSamples.Value(iNSamples(i)));
                end
            else
                app.Input.ThUpar.NSamples = [];
            end
            app.Input.ThUpar.BID = app.Field_ICPBlankID.Value;                              % Blank ID
            app.Input.ThUpar.NatUID = app.Field_ICPNatUID.Value;                            % NatU ID
            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE

            % Isotopic dilution input
            app.Input.WtPar.m_sed = str2double(app.Field_Sediment.Value)';                  % Sediment weights
            app.Input.WtPar.m_4ml = str2double(app.Field_4ml.Value)';                       % 4 mL weights
            app.Input.WtPar.m_10ml = str2double(app.Field_10ml.Value)';                     % 10 mL
            app.Input.WtPar.m_400ul = str2double(app.Field_400ul.Value)';                   % 400 uL
            app.Input.WtPar.m_ThSC = str2double(app.Field_ThSC.Value)';                     % Th spike weight (small cut)
            app.Input.WtPar.m_ThBC = str2double(app.Field_ThBC.Value)';                     % Th spike weight (big cut)
            app.Input.WtPar.m_USC = str2double(app.Field_USC.Value)';                       % U spike weight (small cut)
            app.Input.WtPar.m_UBC = str2double(app.Field_UBC.Value)';                       % U spike weight (big cut)
            app.Input.SpikePar.Th229RY = app.Field_RY229.Value;                             % Th229 RY
            app.Input.SpikePar.Th229CY = app.Field_CY229.Value;                             % Th229 CY
            app.Input.SpikePar.Th230RY = app.Field_RY230.Value;                             % Th230 RY
            app.Input.SpikePar.Th230CY = app.Field_CY230.Value;                             % Th230 CY
            app.Input.SpikePar.U236RY = app.Field_RY236.Value;                              % U236 RY (for 238U ID)
            app.Input.SpikePar.U236RYb = app.Field_RYb236.Value;                            % U236 RY (for 234U ID)
            app.Input.SpikePar.U236CY = app.Field_CY236.Value;                              % U236 CY
            app.Input.SpikeSel = [app.DropDown_229.Value,app.DropDown_230.Value,app.DropDown_236.Value];
            app.Input.cps.Sample = [str2double(app.Field_cps230Th.Value),...                % Th/U sample raw counts
                str2double(app.Field_cps232Th.Value),...
                str2double(app.Field_cps236U.Value),...
                str2double(app.Field_cps238U.Value)]';
            app.Input.cps.Rinse = [str2double(app.Field_cps230Th_R.Value),...               % Th/U rinse raw counts
                str2double(app.Field_cps232Th_R.Value),...
                str2double(app.Field_cps236U_R.Value),...
                str2double(app.Field_cps238U_R.Value)]';

            % Save to export variable 'IN'
            IN = app.Input;
            IN.BatchName{1} = app.BatchName;
        end

        function setInput(app,event,IN)
            %---------------------------------------------------------------------------------
            % Function: Import input parameters
            % This function creates Th232 and U238 isotopic diution vs linear regression
            % plots.
            %% -------------------------------------------------------------------------------

            % Assign input to app property 'Input'
            app.Input = IN;
            clearvars('IN')

            % Set analysis information
            if strcmp(app.Input.AnalysisToggle, 'TEtoggled')
                app.AnalysisSelection.SelectedObject = app.Toggle_TE;            % Analysis toggle
                app.Field_RawDataPath.Value = app.Input.TEpar.RawPath;                      % Raw file directory
                app.Field_SampleID.Value = app.Input.TEpar.SID;                             % Sample ID
                app.Field_BlankID.Value = app.Input.TEpar.pBID;                             % Blank ID
            elseif strcmp(app.Input.AnalysisToggle, 'ThUtoggled')
                app.AnalysisSelection.SelectedObject = app.Toggle_ThU;            % Analysis toggle
                app.Field_RawDataPath.Value = app.Input.ThUpar.RawPath;                     % Raw file directory
                app.Field_SampleID.Value = app.Input.ThUpar.SID;                            % Sample ID
                app.Field_BlankID.Value = app.Input.ThUpar.pBID;                            % Blank ID
            end
            
            % TE corrections
            app.Check_RC.Value = app.Input.TEpar.CCheck.RC;                                 % Rinse correction check
            app.Check_QC.Value = app.Input.TEpar.CCheck.QC;                                 % QC correction check
            app.Check_SC.Value = app.Input.TEpar.CCheck.SC;                                 % Spike correction check
            app.Check_OC.Value = app.Input.TEpar.CCheck.OC;                                 % Oxide correction check
            app.Check_BC.Value = app.Input.TEpar.CCheck.BC;                                 % Blank correction check
            app.Check_DC.Value = app.Input.TEpar.CCheck.DC;                                 % Dilution correction check

            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE
            app.Field_SpikeID.Value = app.Input.TEpar.VID;                                  % Spike ID
            app.Field_RinseID.Value = app.Input.TEpar.RID;                                  % Rinse ID
            app.Field_QCID.Value = app.Input.TEpar.QID;                                     % QC ID

            app.Field_NBlocks.Value = num2str(app.Input.ThUpar.NBlocks);                    % # of analysis blocks
            app.Field_NSamples.Value = num2str(app.Input.ThUpar.NSamples);                  % # of samples per block
            app.Field_ICPBlankID.Value = app.Input.ThUpar.BID;                              % Blank ID
            app.Field_ICPNatUID.Value = app.Input.ThUpar.NatUID;                            % NatU ID
            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE

            %% Set isotopic dilution parameters (where missing input replace NaN by empty field)
            if ~isnan(app.Input.WtPar.m_sed)
                app.Field_Sediment.Value = cellstr(num2str(app.Input.WtPar.m_sed'));            % Sediment weight
            else
                app.Field_Sediment.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_4ml)
                app.Field_4ml.Value = cellstr(num2str(app.Input.WtPar.m_4ml'));                 % 4 mL weight
            else
                app.Field_4ml.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_10ml)
                app.Field_10ml.Value = cellstr(num2str(app.Input.WtPar.m_10ml'));               % 10 mL weight
            else
                app.Field_10ml.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_400ul)
                app.Field_400ul.Value = cellstr(num2str(app.Input.WtPar.m_400ul'));             % 400 uL weight
            else
                app.Field_400ul.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_ThSC)
                app.Field_ThSC.Value = cellstr(num2str(app.Input.WtPar.m_ThSC'));               % Th spike weight (small cut)
            else
                app.Field_ThSC.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_ThBC)
                app.Field_ThBC.Value = cellstr(num2str(app.Input.WtPar.m_ThBC'));               % Th spike weight (big cut)
            else
                app.Field_ThBC.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_USC)
                app.Field_USC.Value = cellstr(num2str(app.Input.WtPar.m_USC'));                 % U spike weight (small cut)
            else
                app.Field_USC.Value = {''};
            end
            if ~isnan(app.Input.WtPar.m_UBC)
                app.Field_UBC.Value = cellstr(num2str(app.Input.WtPar.m_UBC'));                 % U spike weight (big cut)
            else
                app.Field_UBC.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Sample(1,:))) ~= 0
                app.Field_cps230Th.Value = cellstr(num2str(app.Input.cps.Sample(1,:)'));        % Th/U sample raw counts
            else
                app.Field_cps230Th.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Sample(2,:))) ~= 0
                app.Field_cps232Th.Value = cellstr(num2str(app.Input.cps.Sample(2,:)'));
            else
                app.Field_cps232Th.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Sample(3,:))) ~= 0
                app.Field_cps236U.Value = cellstr(num2str(app.Input.cps.Sample(3,:)'));
            else
                app.Field_cps236U.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Sample(4,:))) ~= 0
                app.Field_cps238U.Value = cellstr(num2str(app.Input.cps.Sample(4,:)'));
            else
                app.Field_cps238U.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Rinse(1,:))) ~= 0
                app.Field_cps230Th_R.Value = cellstr(num2str(app.Input.cps.Rinse(1,:)'));       % Th/U rinse raw counts
            else
                app.Field_cps230Th_R.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Rinse(2,:))) ~= 0
                app.Field_cps232Th_R.Value = cellstr(num2str(app.Input.cps.Rinse(2,:)'));
            else
                app.Field_cps232Th_R.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Rinse(3,:))) ~= 0
                app.Field_cps236U_R.Value = cellstr(num2str(app.Input.cps.Rinse(3,:)'));
            else
                app.Field_cps236U_R.Value = {''};
            end
            if sum(~isnan(app.Input.cps.Rinse(4,:))) ~= 0
                app.Field_cps238U_R.Value = cellstr(num2str(app.Input.cps.Rinse(4,:)'));
            else
                app.Field_cps238U_R.Value = {''};
            end

            %% Set spikes and toggle analyses
            Spikes(app,event)
            AnalysisToggle(app,event)
            % Generate sample ID list
            SampleList(app)

        end

        function resetInput(app,event)
            %---------------------------------------------------------------------------------
            % Function: Reset all input fields
            % This function resets all input fields to initial conditions.
            %% -------------------------------------------------------------------------------

            app.BatchName = 'unsaved batch';

            % Reset analysis selection to Th/U (default)
            app.AnalysisSelection.SelectedObject = app.Toggle_ThU;

            % Reset analysis information
            app.Field_RawDataPath.Value = char([]);
            app.Field_SampleID.Value = char([]);
            app.Field_BlankID.Value = char([]);
            
            % Reset TE corrections
            app.Field_QCID.Value = 'QC';
            app.Check_RC.Value = 0;
            app.Check_QC.Value = 1;
            app.Check_SC.Value = 0;
            app.Check_OC.Value = 0;
            app.Check_BC.Value = 1;
            app.Check_DC.Value = 1;

            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE
            app.Field_SpikeID.Value = 'spike';
            app.Field_RinseID.Value = 'Rinse';

            app.Field_NBlocks.Value = char([]);
            app.Field_NSamples.Value = char([]);
            app.Field_ICPBlankID.Value = 'Blank';
            app.Field_ICPNatUID.Value = 'NatU';
            %%%%%%%%%%%%%% MOVE TO PREFERENCE FILE

            % Reset isotopic dilution parameters
            app.Field_SampleList.Value = char([]);
            app.Field_SampleList.Visible = 0;
            app.Field_Sediment.Value = char([]);
            app.Field_4ml.Value = char([]);
            app.Field_10ml.Value = char([]);
            app.Field_400ul.Value = char([]);
            app.Field_ThSC.Value = char([]);
            app.Field_ThBC.Value = char([]);
            app.Field_USC.Value = char([]);
            app.Field_UBC.Value = char([]);
            app.Field_cpsSampleList.Value = char([]);
            app.Field_cpsSampleList.Visible = 0;
            app.Field_cps230Th.Value = char([]);
            app.Field_cps232Th.Value = char([]);
            app.Field_cps236U.Value = char([]);
            app.Field_cps238U.Value = char([]);
            app.Field_cps230Th_R.Value = char([]);
            app.Field_cps232Th_R.Value = char([]);
            app.Field_cps236U_R.Value = char([]);
            app.Field_cps238U_R.Value = char([]);
            app.DropDown_229.Value = 0;
            app.DropDown_230.Value = 0;
            app.DropDown_236.Value = 0;

            % Reset spikes and toggle analyses
            Spikes(app,event)
            AnalysisToggle(app,event)

            % Reset results
            app.Table_ThUresults.Data = [];
            app.Table_TEresults.Data = [];
            cla(app.ThIDFig)
            cla(app.UIDFig)
            app.Label_SampleList.Text = 'ID';
            app.Label_cpsSampleList.Text = 'ID';

        end

        function plotIDLR(app,isotope)
            %---------------------------------------------------------------------------------
            % Function: ID vs LR plot
            % This function creates Th232 and U238 isotopic diution vs linear regression
            % plots.
            %% -------------------------------------------------------------------------------

            if strcmp(isotope,'Th232(ID)')
                xIDFig = 'ThIDFig';
            elseif strcmp(isotope,'U238(ID)')
                xIDFig = 'UIDFig';
            end
            iRx = find(strcmp(app.Output.TE.IsotopesID,isotope));
            xLR = app.Output.TE.C.DC.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            xID = app.Output.TE.C.ID.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            dxLR = app.Output.TE.dC.DC.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            dxID = app.Output.TE.dC.ID.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            [px,~] = polyfit(xLR,xID,1);
            fTh = polyval(px,xLR);
            app.(xIDFig).NextPlot = 'replace';
            errorbar(app.(xIDFig),xLR,xID,dxID,dxID,dxLR,dxLR,'.k','MarkerSize',10,'LineStyle','none')
            line(app.(xIDFig),xLR,fTh,'LineStyle','-','Color','k')
            app.(xIDFig).XLim = [min([app.(xIDFig).XLim(1),app.(xIDFig).YLim(1)]),max([app.(xIDFig).XLim(2),app.(xIDFig).YLim(2)])];
            app.(xIDFig).YLim = [min([app.(xIDFig).XLim(1),app.(xIDFig).YLim(1)]),max([app.(xIDFig).XLim(2),app.(xIDFig).YLim(2)])];
            text(app.(xIDFig),app.(xIDFig).XLim(1)+(app.(xIDFig).XLim(2)-app.(xIDFig).XLim(1))*0.1,app.(xIDFig).YLim(1)+(app.(xIDFig).YLim(2)-app.(xIDFig).YLim(1))*0.9,4,['y = ',num2str(px(1),'%.2f'),'x + ',num2str(px(2),'%.2f')],'FontName','Courier')
            app.(xIDFig).XGrid = 'on';
            app.(xIDFig).YGrid = 'on';
            if strcmp(isotope,'Th232(ID)')
                app.(xIDFig).XLabel.String = '232Th, LR (ppm)';
                app.(xIDFig).YLabel.String = '232Th, ID (ppm)';
            elseif strcmp(isotope,'U238(ID)')
                app.(xIDFig).XLabel.String = '238U, LR (ppm)';
                app.(xIDFig).YLabel.String = '238U, ID (ppm)';
            end
            app.(xIDFig).FontName = 'Courier';
        end

        function discard = CheckForChanges(app,msg,button)
            %---------------------------------------------------------------------------------
            % Function: Check for unsaved changes
            % This function scans the input for unsaved changes. Executed prior to loading a
            % new batch file, clearing the current input, and quitting ICPro.
            %% -------------------------------------------------------------------------------

            % Get all input data, parameters and selections
            INx = getInput(app);
            if isfield(INx,'SType')
                INx = rmfield(INx,'SType');
            end

            % Get currently loaded batch file or new batch window state (saved as ~newbatch.mat)
            if ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch')
                load([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,app.BatchName],'IN');
                if isfield(IN,'SType')
                    IN = rmfield(IN,'SType');
                    save([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,app.BatchName],'IN');
                end
            else
                INx.BatchName = {'~newbatch'};
                load([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,'~newbatch.mat'],'IN');
            end

            % Compare input to batch file, in case of difference initiate 'unsaved changes' prompt
            if ~isequaln(IN,INx)
                title = 'Unsaved changes';
                selection = questdlg(msg,title,button,'Cancel',button);
                if strcmp(selection,button)
                    discard = true;
                else
                    discard = false;
                end
            else
                discard = true;
            end

            clearvars('INx')
        end

        function SampleMatch(app)
            %---------------------------------------------------------------------------------
            % Function: Match up TE and Th/U sample IDs
            % This function matches TE and Th/U sample IDs based on their numbering. Only if
            % both TE and Th/U analyses are performed.
            %% -------------------------------------------------------------------------------

            %  Get ThU Sample IDs (ThU samples sorted into blocks with occasional NaN fields)
            app.SMatch.ThUind = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
            ThUSamples = app.Output.ThU.SampleSeq(app.SMatch.ThUind);

            % Determine TE/ThU sample matchups for ID
            if numel(ThUSamples) == numel(app.Output.TE.RunID.Sample)+1
                % If # of ThU samples equals # of TE samples, match up against each TE sample
                app.SMatch.TEind = 1 : numel(app.Output.TE.RunID.Sample)+1;
            elseif numel(ThUSamples) < numel(app.Output.TE.RunID.Sample)+1
                % If # of ThU samples is smaller than # of TE samples, ThU samples were likely analysed in separate batches
                % Determine TE sample ID numbers
                [s,e] = regexp(app.Output.TE.RunID.Sample,'[0-9]*');
                TEIDnum = NaN(1,numel(app.Output.TE.RunID.Sample));
                for i = 1 : numel(app.Output.TE.RunID.Sample)
                    TEIDnum(i) = str2double(app.Output.TE.RunID.Sample{i}(s{i}(end):e{i}(end)));
                end
                % Determine ThU sample ID numbers & find corresponding TE sample matchups
                [s,e] = regexp(ThUSamples,'[0-9]*');
                ThUIDnum = NaN(1,numel(ThUSamples));
                app.SMatch.TEind = NaN(1,numel(ThUSamples));
                for i = 1 : numel(ThUSamples)
                    ThUIDnum(i) = str2double(ThUSamples{i}(s{i}(end):e{i}(end)));
                    app.SMatch.TEind(i) = find(ThUIDnum(i) == TEIDnum);
                end
            end
            % Create matchup table
            ThUMatchUp = repmat({'<NA>'},numel(app.Output.TE.RunID.Sample)+1,1);ThUMatchUp(app.SMatch.TEind) = ThUSamples';
            app.SMatch.Table = cell2table([[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample],ThUMatchUp],'VariableNames',{'TE Samples','ThU Samples'});
            app.SMatch.IDcat = strcat(app.SMatch.Table{:,1},'/',app.SMatch.Table{:,2});
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Splash(app)
            %---------------------------------------------------------------------------------
            % Callback: Splash screen
            % This function displays a splash screen when ICPro is launched
            %% -------------------------------------------------------------------------------

            % Add ICPro functions path to Matlab path
            addpath([app.AppPath.folder,filesep,'misc',filesep,'functions',filesep])
            addpath([app.AppPath.folder,filesep,'misc',filesep,'functions',filesep])

            % Set button icons
            set(app.Button_ThUtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
            set(app.Button_TEtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
            set(app.Button_Run,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'go.png'])

            % Splash screen
            pause(1.5)
            p = rand(5);
            s = SplashScreen( 'Splashscreen', [app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'appicon_square.png'], ...
                'ProgressBar', 'on', ...
                'ProgressPosition', 5, ...
                'ProgressRatio', 0.1 );
            s.addText(10, 280, 'Initialising...', 'FontSize', 14, 'Color', [.6 .6 .6], 'FontName', 'Avenir')
            pause(p(1))
            s.ProgressRatio = .2;
            pause(p(2))
            s.ProgressRatio = .6;
            pause(p(3))
            s.ProgressRatio = .8;
            pause(p(4))
            s.ProgressRatio = 1;
            pause(p(5))
            delete(s)

        end

        % Button pushed function: Button_Run
        function Execute(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Execute data processing
            % This function initiates the processing of TE and/or ThU raw data based on the
            % provided input parameters and selections. Results displayed in UI.
            %% -------------------------------------------------------------------------------

            % Get all input data, parameters and selections and save to app property 'Input' (no output)
            [~] = getInput(app);

            % Determine analyses' toggle state
            State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
            State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;
                
            % Waitbar setup
            if State.TE
                w(1) = 0;
                w(2) = 1;
                msg{1} = 'Trace Element processing ...';
                msg{2} = {['Trace Element processing ', char(hex2dec('2713'))]};
            elseif State.ThU
                w(1) = 0;
                w(2) = 1;
                msg{1} = 'Th/U processing ...';
                msg{2} = {['Th/U processing ', char(hex2dec('2713'))]};
            elseif app.Button_TEtoggle.Value && app.Button_ThUtoggle.Value
                w(1) = 0;
                w(2) = .5;
                w(3) = 1;
                msg{1} = {'Trace Element processing ...';'Th/U processing ...'};
                msg{2} = {['Trace Element processing ', char(hex2dec('2713'))];'Th/U processing ...'};
                msg{3} = {['Trace Element processing ', char(hex2dec('2713'))];['Th/U processing ', char(hex2dec('2713'))]};
            end

            % Waitbar (intial)
            wbexist = findall(groot,'type','Figure','-and','tag','TMWWaitbar');
            if ~isempty(wbexist)
                close(wbexist)
            end
            wb = waitbar(w(1),msg{1});
            wb.Position(4) = wb.Position(4);

            % Trace Element processing
            if app.AnalysisSelection.SelectedObject == app.Toggle_TE
                % Read & process TE raw data
                app.Output.TE = TE_ReadRaw(app.Input.TEpar,1);
                app.Output.TE = TE_Processor(app.Input.TEpar,app.Output.TE.C,app.Output.TE.dC,app.Output.TE.Isotopes,app.Output.TE.RunID,app.Input.WtPar,app.Input.SpikePar,app.Input.cps);

                % Display results
                if app.Check_DC.Value
                    % Display corrected concentrations in table (isotopic dilution)
                    app.Table_TEresults.Data = [app.Output.TE.C.DC.f_DC',app.Output.TE.C.ID.Sample'];
                    app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.IsotopesID];
                    app.Table_TEresults.RowName = app.Output.TE.RunID.Sample;
                    app.CUSelTE.SelectedObject = app.ConcTE;
                    setappdata(app.ICProUIFigure,'ActiveResultTE',app.DataSelTE.SelectedObject.Tag);

                    % Plot ID vs linear calibration
                    plotIDLR(app,'Th232(ID)')
                    plotIDLR(app,'U238(ID)')
                else
                    % Display raw concentrations in table
                    app.Table_TEresults.Data = app.Output.TE.C.Raw.Sample';
                    app.Table_TEresults.ColumnName = app.Output.TE.Isotopes;
                    app.Table_TEresults.RowName = app.Output.TE.RunID.Sample;
                    app.CUSelTE.SelectedObject = app.ConcTE;
                    setappdata(app.ICProUIFigure,'ActiveResultTE',app.DataSelTE.SelectedObject.Tag);

                    % Clear ID vs LR figures
                    cla(app.ThIDFig)
                    cla(app.UIDFig)
                end

                % Enable export menu items
                app.TEsheet.Enable = app.Check_DC.Value;        % TE result sheet export
                app.IndResExp.Enable = 'on';                    % Individual result export menu
                app.TEIndRes.Enable = 'on';                     % TE individual result export menu
                app.Rawexport.Enable = 'on';                    % Individual raw data export
                app.RCexport.Enable = app.Check_RC.Value;       % Individual rinse corrected data export
                app.QCexport.Enable = app.Check_QC.Value;       % Individual QC corrected data export
                app.SCexport.Enable = app.Check_SC.Value;       % Individual spike corrected data export
                app.OCexport.Enable = app.Check_OC.Value;       % Individual oxide corrected data export
                app.BCexport.Enable = app.Check_BC.Value;       % Individual blank corrected data export
                app.DCexport.Enable = app.Check_DC.Value;       % Individual dilution corrected data export
                app.IDexport.Enable = app.Check_DC.Value;       % Individual isotopic dilution corrected data export
                app.IDLRplots.Enable = 'on';                    % ID vs LR plot export

                % Enable result switcher buttons and preselect results to preview
                app.RawButton.Enable = 'on';                    % Raw data selection button
                app.RCButton.Enable = app.Check_RC.Value;       % Rinse correceted data selection button
                app.QCButton.Enable = app.Check_QC.Value;       % QC correceted data selection button
                app.SCButton.Enable = app.Check_SC.Value;       % Spike correceted data selection button
                app.OCButton.Enable = app.Check_OC.Value;       % Oxide correceted data selection button
                app.BCButton.Enable = app.Check_BC.Value;       % Blank correceted data selection button
                app.DCButton.Enable = app.Check_DC.Value;       % Dilution correceted data selection button
                app.IDButton.Enable = app.Check_DC.Value;       % Isotopic dilution correceted data selection button
                app.ConcTE.Enable = 'on';                       % Concentration selection button
                app.ErrTE.Enable = 'on';                        % Error selection button
                app.ErrpTE.Enable = 'on';                       % Percentage error selection button
                app.CUSelTE.SelectedObject = app.ConcTE;        % Preselect concentration display
                if app.Check_DC.Value
                    app.DataSelTE.SelectedObject = app.IDButton;    % Preselect isotopic dilution corrected data
                else
                    app.DataSelTE.SelectedObject = app.RawButton;   % Preselect raw data (if no dilution correction was performed)
                end
            else
                % Clear TE result table and ID vs LR figures
                app.Table_TEresults.Data = [];
                cla(app.ThIDFig)
                cla(app.UIDFig)

                % Enable export menu items
                app.TEsheet.Enable = 'off';        % TE result sheet export
                %                 app.IndResExp.Enable = 'off';                    % Individual result export menu
                app.TEIndRes.Enable = 'off';                     % TE individual result export menu
                app.Rawexport.Enable = 'off';                    % Individual raw data export
                app.RCexport.Enable = app.Check_RC.Value;       % Individual rinse corrected data export
                app.QCexport.Enable = app.Check_QC.Value;       % Individual QC corrected data export
                app.SCexport.Enable = app.Check_SC.Value;       % Individual spike corrected data export
                app.OCexport.Enable = app.Check_OC.Value;       % Individual oxide corrected data export
                app.BCexport.Enable = app.Check_BC.Value;       % Individual blank corrected data export
                app.DCexport.Enable = app.Check_DC.Value;       % Individual dilution corrected data export
                app.IDexport.Enable = app.Check_DC.Value;       % Individual isotopic dilution corrected data export
                app.IDLRplots.Enable = 'off';                    % ID vs LR plot export

                % Enable result switcher buttons and preselect results to preview
                app.RawButton.Enable = 'off';                    % Raw data selection button
                app.RCButton.Enable = app.Check_RC.Value;       % Rinse correceted data selection button
                app.QCButton.Enable = app.Check_QC.Value;       % QC correceted data selection button
                app.SCButton.Enable = app.Check_SC.Value;       % Spike correceted data selection button
                app.OCButton.Enable = app.Check_OC.Value;       % Oxide correceted data selection button
                app.BCButton.Enable = app.Check_BC.Value;       % Blank correceted data selection button
                app.DCButton.Enable = app.Check_DC.Value;       % Dilution correceted data selection button
                app.IDButton.Enable = app.Check_DC.Value;       % Isotopic dilution correceted data selection button
                app.ConcTE.Enable = 'off';                       % Concentration selection button
                app.ErrTE.Enable = 'on';                        % Error selection button
                app.ErrpTE.Enable = 'on';                       % Percentage error selection button
                app.CUSelTE.SelectedObject = app.ConcTE;        % Preselect concentration display
                if app.Check_DC.Value
                    app.DataSelTE.SelectedObject = app.IDButton;    % Preselect isotopic dilution corrected data
                else
                    app.DataSelTE.SelectedObject = app.RawButton;   % Preselect raw data (if no dilution correction was performed)
                end
            end

            % Waitbar (intermediate)
            if  numel(w) == 3
                % Waitbar end
                wb = waitbar(w(2),wb,msg{2});
                wb.Position(4) = wb.Position(4);
            end

            % Th/U processing
            if app.AnalysisSelection.SelectedObject == app.Toggle_ThU
                % Calculate Th/U ratios from ICP-MS raw counts & Th230/U236 isotopic dilution
                app.Output.ThU = ThU_Processor(app.Input.ThUpar,app.Input.SpikePar,app.Input.WtPar,1);
                ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));

                % Display recovery plot
                recov229 = app.Output.ThU.Ratios.Sample.cpsMean(1,:,:);
                recov236 = app.Output.ThU.Ratios.Sample.cpsMean(7,:,:);
                app.RecoveryFig.NextPlot = 'replace';
                yyaxis(app.RecoveryFig,'left')
                set(app.RecoveryFig,'YColor',[0 0 0])
                bar(app.RecoveryFig,(.85:numel(recov229(ThUSampleInd))-.15),recov229(ThUSampleInd),0.3,'k')
                app.RecoveryFig.YLabel.String = 'raw counts [cps]';
                yyaxis(app.RecoveryFig,'right')
                set(app.RecoveryFig,'YColor',[.5 .5 .5])
                app.RecoveryFig.YLabel.String = 'raw counts [cps]';
                b = bar(app.RecoveryFig,(1.15:numel(recov236(ThUSampleInd))+.15),recov236(ThUSampleInd),0.3);
                b.FaceColor = [.7 .7 .7];
                app.RecoveryFig.XTick = 1:numel(recov236(ThUSampleInd));
                app.RecoveryFig.XTickLabel = app.Output.ThU.SampleSeq(ThUSampleInd);
                legend(app.RecoveryFig,'^{229}Th','^{236}U','NumColumns',2,'Location','north')
                app.RecoveryFig.Units = 'pixels';
                app.RecoveryFig.Position = [25,15,750,380];
                app.RecoveryFig.PlotBoxAspectRatioMode = 'auto';
                app.RecoveryFig.FontName = 'Courier';
                app.RecoveryFig.XTickLabelRotation = 45;
                app.RecoveryFig.TickLabelInterpreter = 'none';
                app.RecoveryFig.FontSize = 12;

                % Display results in table
                app.ThUIDButton.Value = 1;
                app.ConcThU.Value = 1;
                if ~isempty(app.Field_BIDThU.Value)
                    app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC];
                    app.Table_ThUresults.ColumnName = {'Th230 (ID), blank corrected','U234 (ID), blank corrected'};
                    app.ThUBCcheck.Enable = 'on';
                    app.ThUBCcheck.Value = 1;
                else
                    app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234];
                    app.Table_ThUresults.ColumnName = {'Th230 (ID)','U234 (ID)'};
                    app.ThUBCcheck.Enable = 'off';
                    app.ThUBCcheck.Value = 0;
                end
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.CUSelThU.SelectedObject = app.ConcThU;
                app.ConcThU.Text = '  Conc. (pg/g)';
                app.ErrThU.Text = '  Errors (pg/g)';
                setappdata(app.ICProUIFigure,'ActiveResultThU',app.DataSelThU.SelectedObject.Tag);

                % Enable export menu items
                app.ThUsheet.Enable = 'on';                 % Th/U result sheet export
                app.IndResExp.Enable = 'on';                % Individual result export menu
                app.ThUIndRes.Enable = 'on';                % Th/U individual result export menu
                app.ThUIDExp.Enable = 'on';                 % Individual Th & U isotopic dilution export
                app.SamRatioExp.Enable = 'on';              % Individual sample Th & U ratio export
                app.NatURatioExp.Enable = 'on';             % Individual NatU Th & U ratio export

                % Enable result switcher buttons and preselect results to preview
                app.ThUIDButton.Enable = 'on';              % Th & U isotopic dilution button
                app.RatioSButton.Enable = 'on';             % Sample Th & U ratio button
                app.RatioNButton.Enable = 'on';             % NatU Th & U ratio button
                app.ConcThU.Enable = 'on';                  % Concentration/Ratio selection button
                app.ErrThU.Enable = 'on';                   % Error selection button
                app.ErrpThU.Enable = 'on';                  % Percentage error slection button
                app.CUSelThU.SelectedObject = app.ConcThU;       % Preselect concentration display
                app.DataSelThU.SelectedObject = app.ThUIDButton; % Preselect isotopic dilution display
                app.ThUBCcheck.Enable = 'on';               % Blank correction check
            else
                yyaxis(app.RecoveryFig,'left')
                cla(app.RecoveryFig)
                yyaxis(app.RecoveryFig,'right')
                cla(app.RecoveryFig)
                app.Table_ThUresults.Data = [];
                % Enable export menu items
                app.ThUsheet.Enable = 'off';                 % Th/U result sheet export
                %                 app.IndResExp.Enable = 'off';                % Individual result export menu
                app.ThUIndRes.Enable = 'off';                % Th/U individual result export menu
                app.ThUIDExp.Enable = 'off';                 % Individual Th & U isotopic dilution export
                app.SamRatioExp.Enable = 'off';              % Individual sample Th & U ratio export
                app.NatURatioExp.Enable = 'off';             % Individual NatU Th & U ratio export

                % Enable result switcher buttons and preselect results to preview
                app.ThUIDButton.Enable = 'off';              % Th & U isotopic dilution button
                app.RatioSButton.Enable = 'off';             % Sample Th & U ratio button
                app.RatioNButton.Enable = 'off';             % NatU Th & U ratio button
                app.ConcThU.Enable = 'off';                  % Concentration/Ratio selection button
                app.ErrThU.Enable = 'off';                   % Error selection button
                app.ErrpThU.Enable = 'off';                  % Percentage error slection button
                app.CUSelThU.SelectedObject = app.ConcThU;       % Preselect concentration display
                app.DataSelThU.SelectedObject = app.ThUIDButton; % Preselect isotopic dilution display
                app.ThUBCcheck.Enable = 'off';               % Blank correction check
            end

            % Waitbar (end)
            wb = waitbar(w(end),wb,msg{end});
            wb.Position(4) = wb.Position(4);

            AnalysisToggle(app,event)

            % Bring ICPro to foreground
            waitfor(wb)
            figure(app.ICProUIFigure)
        end

        % Callback function: AnalysisSelection, Button_TEtoggle, 
        % Button_ThUtoggle, Check_DC
        function AnalysisToggle(app, event)
            %---------------------------------------------------------------------------------
            % Callback: (Un)toggle ThU and TE processing
            % This function toggles or untoggles the processing of TE and ThU. Based on the
            % processing steps selected, required and obsolete input fields will be enabled
            % and disbaled, respectively.
            %% -------------------------------------------------------------------------------

            if event.Source == app.Button_TEtoggle
                % Determine analyses' toggle state
                State.TE = app.Button_TEtoggle.Value && ~app.Button_ThUtoggle.Value;
                State.ThU = ~app.Button_TEtoggle.Value && app.Button_ThUtoggle.Value;
                State.both = app.Button_TEtoggle.Value && app.Button_ThUtoggle.Value;
                State.none = ~app.Button_TEtoggle.Value && ~app.Button_ThUtoggle.Value;

                % Disable/Enable input fields
                % Get fields for enabling/disabling
                Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229','-and','-regexp','Type','DropDown','-or','-regexp','Tag','229','-and','-regexp','Type','NumericEditField');
                Th229SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','229','-and','-regexp','Type','Button');
                Th230SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','230','-and','-regexp','Type','DropDown','-or','-regexp','Tag','230','-and','-regexp','Type','NumericEditField');
                Th230SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','230','-and','-regexp','Type','Button');
                Th236SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','236','-and','-regexp','Type','DropDown','-or','-regexp','Tag','236','-and','-regexp','Type','NumericEditField');
                Th236SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','236','-and','-regexp','Type','Button');
                WeightTabFields = findall(app.Tab_IDWeights,'-regexp','Type','TextArea','-not',{'-regexp','Tag','Field_ThBC','-or','-regexp','Tag','SC','-or','-regexp','Tag','BC'});
                RawIntTabFields = findall(app.Tab_RawIntensities,'-regexp','Type','TextArea','-or','-regexp','Type','Button');
                if event.Source == app.Button_TEtoggle || event.Source == app.Button_ThUtoggle
                    TEResuTabFields = [];
                    ThUResTabFields = [];
                else
                    TEResuTabFields = findall(app.Tab_TEresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                    ThUResTabFields = findall(app.Tab_ThUresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                end

                % Change toggle button appearances & disable/enable panels
                if event.Source ~= app.Check_DC
                    if State.TE
                        set(app.Panel_AnalysisInfo,'Enable','on')
                        set(app.Button_TEtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'tick.png'])
                        set(app.Button_TEtoggle,'BackgroundColor',[.5 1 .5])
                        set(app.Panel_ThU,'Enable','off')
                        set(app.Button_ThUtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
                        set(app.Button_ThUtoggle,'BackgroundColor',[1 .5 .5])
                    elseif State.ThU
                        set(app.Panel_AnalysisInfo,'Enable','off')
                        set(app.Button_TEtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
                        set(app.Button_TEtoggle,'BackgroundColor',[1 .5 .5])
                        set(app.Panel_ThU,'Enable','on')
                        set(app.Button_ThUtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'tick.png'])
                        set(app.Button_ThUtoggle,'BackgroundColor',[.5 1 .5])
                    elseif State.both
                        set(app.Panel_AnalysisInfo,'Enable','on')
                        set(app.Button_TEtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'tick.png'])
                        set(app.Button_TEtoggle,'BackgroundColor',[.5 1 .5])
                        set(app.Panel_ThU,'Enable','on')
                        set(app.Button_ThUtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'tick.png'])
                        set(app.Button_ThUtoggle,'BackgroundColor',[.5 1 .5])
                    elseif State.none
                        set(app.Panel_AnalysisInfo,'Enable','off')
                        set(app.Button_TEtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
                        set(app.Button_TEtoggle,'BackgroundColor',[1 .5 .5])
                        set(app.Panel_ThU,'Enable','off')
                        set(app.Button_ThUtoggle,'Icon',[app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'x.png'])
                        set(app.Button_ThUtoggle,'BackgroundColor',[1 .5 .5])
                    end
                end

                % Specify fields to be disabled/enabled for each analysis selection state
                if State.TE
                    if true(app.Check_DC.Value)
                        % DISABLE
                        DisableFields = [...
                            app.Field_ThBC;...                              % [-] Th(BC) weight field
                            ThUResTabFields;...                             % [-] Th/U results tab
                            Th229SpikeFields;Th229SpikeButtns...            % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            ];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            WeightTabFields;...                             % [+] All non-spike weight fields
                            RawIntTabFields;...                             % [+] All raw intensity input fields
                            app.Field_UBC;app.Field_ThSC;app.Field_USC;...  % [+] U(BC), Th(SC), U(SC) weight fields
                            Th236SpikeFields;Th236SpikeButtns;...           % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                            Th230SpikeFields;Th230SpikeButtns;...           % [+] Th230 spike fields and buttons (incl. U236 RYa)
                            app.Label_SampleList                            % [+] Sample ID list generate button
                            ];
                    else
                        % DISABLE
                        DisableFields = [...
                            WeightTabFields;...                             % [-] All non-spike weight fields
                            RawIntTabFields;...                             % [-] All raw intensity input fields
                            app.Field_ThBC;app.Field_ThSC;...               % [-] Th(BC), Th(SC) weight fields
                            app.Field_UBC;app.Field_USC;...                 % [-] U(BC), U(SC) weight fields
                            Th229SpikeFields;Th229SpikeButtns;...           % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            Th230SpikeFields;Th230SpikeButtns;...           % [-] Th230 spike fields and buttons (incl. U236 RYa)
                            Th236SpikeFields;Th236SpikeButtns;...           % [-] Th236 spike fields and buttons (excl. U236 RYa/b)
                            ThUResTabFields;...                             % [-] Th/U results tab
                            app.Label_SampleList                            % [-] Sample ID list generate button
                            ];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            ];
                    end
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'TE ID';
                    app.Label_cpsSampleList.Text = 'TE ID';
                elseif State.ThU
                    % DISABLE
                    DisableFields = [...
                        app.Field_ThSC;app.Field_USC;...                    % [-] Th(SC), U(SC) weight field
                        RawIntTabFields;...                                 % [-] All raw intensity input fields
                        TEResuTabFields;...                                 % [-] TE results
                        Th230SpikeFields;Th230SpikeButtns...                % [-] Th230 spike fields and buttons (incl. U236 RYa)
                        ];
                    % ENABLE
                    EnableFields = [...
                        app.Button_Run;...                                  % [+] Execute button
                        WeightTabFields;...                                 % [+] All non-spike weight fields
                        app.Field_ThBC;app.Field_UBC;...                    % [+] Th(BC), U(BC) weight fields
                        Th236SpikeFields;Th236SpikeButtns;...               % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                        Th229SpikeFields;Th229SpikeButtns;...               % [+] Th229 spike fields and buttons (incl. U236 RYb)
                        app.Label_SampleList                                % [+] Sample ID list generate button
                        ];
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'ThU ID';
                    app.Label_cpsSampleList.Text = 'ThU ID';
                elseif State.both
                    if true(app.Check_DC.Value)
                        % DISABLE
                        DisableFields = [];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            WeightTabFields;...                             % [+] All non-spike weight fields
                            RawIntTabFields;...                             % [+] All raw intensity input fields
                            app.Field_ThBC;app.Field_ThSC;...               % [+] Th(BC), Th(SC) weight fields
                            app.Field_UBC;app.Field_USC;...                 % [+] U(BC), U(SC) weight fields
                            app.SpikesPanel;...                             % [+] Spike panel
                            Th236SpikeFields;Th236SpikeButtns;...           % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                            Th230SpikeFields;Th230SpikeButtns;...           % [+] Th230 spike fields and buttons (incl. U236 RYa)
                            Th229SpikeFields;Th229SpikeButtns;...           % [+] Th229 spike fields and buttons (incl. U236 RYb)
                            app.Label_SampleList                            % [+] Sample ID list generate button
                            ];
                    else
                        % DISABLE
                        DisableFields = [...
                            app.Field_ThSC;app.Field_USC;...                % [-] Th(SC), U(SC) weight field
                            TEResuTabFields;...                             % [-] TE results
                            Th230SpikeFields;Th230SpikeButtns...            % [-] Th230 spike fields and buttons (incl. U236 RYa)
                            ];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            WeightTabFields;...                             % [+] All non-spike weight fields
                            app.Field_ThBC;app.Field_UBC;...                % [+] Th(BC), U(BC) weight fields
                            Th236SpikeFields;Th236SpikeButtns;...           % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                            Th229SpikeFields;Th229SpikeButtns;...           % [+] Th229 spike fields and buttons (incl. U236 RYb)
                            app.Label_SampleList                           % [+] Sample ID list generate button
                            ];
                    end
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'TE/ThU ID';
                    app.Label_cpsSampleList.Text = 'TE/ThU ID';
                elseif State.none
                    % DISABLE
                    DisableFields = [...
                        app.Button_Run;...                                  % [-] Execute button
                        WeightTabFields;...                                 % [-] All non-spike weight fields
                        RawIntTabFields;...                                 % [-] All raw intensity input fields
                        app.Field_ThBC;app.Field_ThSC;...                   % [-] Th(BC), Th(SC) weight fields
                        app.Field_UBC;app.Field_USC;...                     % [-] U(BC), U(SC) weight fields
                        Th236SpikeFields;Th236SpikeButtns;...               % [-] Th236 spike fields and buttons (excl. U236 RYa/b)
                        Th229SpikeFields;Th229SpikeButtns;...               % [-] Th229 spike fields and buttons (incl. U236 RYb)
                        Th230SpikeFields;Th230SpikeButtns;...               % [-] Th230 spike fields and buttons (incl. U236 RYa)
                        TEResuTabFields;...                                 % [-] TE results
                        ThUResTabFields;...                                 % [-] Th/U results tab
                        app.Label_SampleList                                % [-] Sample ID list generate button
                        ];
                    % ENABLE
                    EnableFields = [];
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'ID';
                    app.Label_cpsSampleList.Text = 'ID';
                end

                if app.DropDown_229.Value == 0
                    DisableFields = [DisableFields;Th229SpikeButtns];
                else
                    EnableFields = [EnableFields;Th229SpikeButtns];
                end
                if app.DropDown_230.Value == 0
                    DisableFields = [DisableFields;Th230SpikeButtns];
                else
                    EnableFields = [EnableFields;Th230SpikeButtns];
                end
                if app.DropDown_236.Value == 0
                    DisableFields = [DisableFields;Th236SpikeButtns];
                else
                    EnableFields = [EnableFields;Th236SpikeButtns];
                end

                for iE = 1 : length(EnableFields)
                    EnableFields(iE).Enable = 'on';
                end
                for iD = 1 : length(DisableFields)
                    DisableFields(iD).Enable = 'off';
                end
            end

            if event.Source == app.AnalysisSelection
                % Determine analyses' toggle state
                State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
                State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

                % Get all fields which need to be enabled or disabled
                Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229','-and','-regexp','Type','DropDown','-or','-regexp','Tag','229','-and','-regexp','Type','NumericEditField');
                Th229SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','229','-and','-regexp','Type','Button');
                Th230SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','230','-and','-regexp','Type','DropDown','-or','-regexp','Tag','230','-and','-regexp','Type','NumericEditField');
                Th230SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','230','-and','-regexp','Type','Button');
                Th236SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','236','-and','-regexp','Type','DropDown','-or','-regexp','Tag','236','-and','-regexp','Type','NumericEditField');
                Th236SpikeButtns = findall(app.SpikesPanel,'-regexp','Tag','236','-and','-regexp','Type','Button');
                WeightTabFields = findall(app.Tab_IDWeights,'-regexp','Type','TextArea','-not',{'-regexp','Tag','Field_ThBC','-or','-regexp','Tag','SC','-or','-regexp','Tag','BC'});
                RawIntTabFields = findall(app.Tab_RawIntensities,'-regexp','Type','TextArea','-or','-regexp','Type','Button');
                if event.Source == app.AnalysisSelection
                    TEResuTabFields = [];
                    ThUResTabFields = [];
                else
                    TEResuTabFields = findall(app.Tab_TEresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                    ThUResTabFields = findall(app.Tab_ThUresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                end

                % Determine fields to disable/enable based on analysis selected
                if State.TE
                    if true(app.Check_DC.Value)
                        % DISABLE
                        DisableFields = [...
                            app.Field_ThBC;...                              % [-] Th(BC) weight field
                            ThUResTabFields;...                             % [-] Th/U results tab
                            Th229SpikeFields;Th229SpikeButtns...            % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            ];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            app.Panel_TECorr;...                            % [+] TE correction panel
                            WeightTabFields;...                             % [+] All non-spike weight fields
                            RawIntTabFields;...                             % [+] All raw intensity input fields
                            app.Field_UBC;app.Field_ThSC;app.Field_USC;...  % [+] U(BC), Th(SC), U(SC) weight fields
                            Th236SpikeFields;Th236SpikeButtns;...           % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                            Th230SpikeFields;Th230SpikeButtns;...           % [+] Th230 spike fields and buttons (incl. U236 RYa)
                            app.Label_SampleList                            % [+] Sample ID list generate button
                            ];
                    else
                        % DISABLE
                        DisableFields = [...
                            WeightTabFields;...                             % [-] All non-spike weight fields
                            RawIntTabFields;...                             % [-] All raw intensity input fields
                            app.Field_ThBC;app.Field_ThSC;...               % [-] Th(BC), Th(SC) weight fields
                            app.Field_UBC;app.Field_USC;...                 % [-] U(BC), U(SC) weight fields
                            Th229SpikeFields;Th229SpikeButtns;...           % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            Th230SpikeFields;Th230SpikeButtns;...           % [-] Th230 spike fields and buttons (incl. U236 RYa)
                            Th236SpikeFields;Th236SpikeButtns;...           % [-] Th236 spike fields and buttons (excl. U236 RYa/b)
                            ThUResTabFields;...                             % [-] Th/U results tab
                            app.Label_SampleList                            % [-] Sample ID list generate button
                            ];
                        % ENABLE
                        EnableFields = [...
                            app.Button_Run;...                              % [+] Execute button
                            app.Panel_TECorr;...                            % [+] TE correction panel
                            ];
                    end
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'TE ID';
                    app.Label_cpsSampleList.Text = 'TE ID';
                elseif State.ThU
                    % DISABLE
                    DisableFields = [...
                        app.Field_ThSC;app.Field_USC;...                    % [-] Th(SC), U(SC) weight field
                        app.Panel_TECorr;...                                % [-] TE correction panel
                        app.Field_4ml;app.Field_10ml;app.Field_400ul;...    % [-] 4ml, 10ml, 400 ul weight fields
                        RawIntTabFields;...                                 % [-] All raw intensity input fields
                        TEResuTabFields;...                                 % [-] TE results
                        Th230SpikeFields;Th230SpikeButtns...                % [-] Th230 spike fields and buttons (incl. U236 RYa)
                        ];
                    % ENABLE
                    EnableFields = [...
                        app.Button_Run;...                                  % [+] Execute button
                        app.Field_Sediment;...                              % [+] Sediment weight
                        app.Field_ThBC;app.Field_UBC;...                    % [+] Th(BC), U(BC) weight fields
                        Th236SpikeFields;Th236SpikeButtns;...               % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                        Th229SpikeFields;Th229SpikeButtns;...               % [+] Th229 spike fields and buttons (incl. U236 RYb)
                        app.Label_SampleList                                % [+] Sample ID list generate button
                        ];
                    % Change Sample ID list title
                    app.Label_SampleList.Text = 'ThU ID';
                    app.Label_cpsSampleList.Text = 'ThU ID';
                end

                if app.DropDown_229.Value == 0
                    DisableFields = [DisableFields;Th229SpikeButtns];
                else
                    EnableFields = [EnableFields;Th229SpikeButtns];
                end
                if app.DropDown_230.Value == 0
                    DisableFields = [DisableFields;Th230SpikeButtns];
                else
                    EnableFields = [EnableFields;Th230SpikeButtns];
                end
                if app.DropDown_236.Value == 0
                    DisableFields = [DisableFields;Th236SpikeButtns];
                else
                    EnableFields = [EnableFields;Th236SpikeButtns];
                end

                % Disable and enable fields
                for iE = 1 : length(EnableFields)
                    EnableFields(iE).Enable = 'on';
                end
                for iD = 1 : length(DisableFields)
                    DisableFields(iD).Enable = 'off';
                end

            end
        end

        % Button pushed function: Button_RawDataPath, 
        % Button_RawFolder
        function BrowseRawData(app, event)
            %---------------------------------------------------------------------------------
            % Callback: File browser UI
            % This function initiates a file browser UI, allowing the user to locate raw data
            % files and folders.
            %% -------------------------------------------------------------------------------

            if app.AnalysisSelection.SelectedObject == app.Toggle_TE
                [TERawFile,TERawPath] = uigetfile('.xlsx','Pick a Trace Element raw file');
                if TERawPath ~= 0
                    set(app.Field_RawDataPath,'Value',[TERawPath,TERawFile])
                end
            elseif app.AnalysisSelection.SelectedObject == app.Toggle_ThU
                ThURawPath = uigetdir('','Pick a Th/U raw data folder');
                if ThURawPath ~= 0
                    set(app.Field_RawDataPath,'Value',ThURawPath)
                end
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Button pushed function: Label_SampleList
        function SampleList(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Generate sample ID list
            % This function generates a list of sample IDs next to input fields.
            %% -------------------------------------------------------------------------------

            [~] = getInput(app);
            
            % Determine analyses' toggle state
            State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
            State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

            TEallin = true;%~isempty(app.Field_RawDataPath.Value) && ~isempty(app.Field_SampleID.Value) && ~isempty(app.Field_BlankID.Value);
            ThUallin = true;%~isempty(app.Field_RawFolder.Value) && ~isempty(app.Field_NBlocks.Value) && ~isempty(app.Field_NSamples.Value) && ~isempty(app.Field_SIDThU.Value);

            % CASE 1: TE + dilution correction
            if State.TE
                % Only attempt to generate sample list if all required inputs are provided
                if TEallin
                    % Get TE IDs by running reduced version of TE_ReadRaw (input 0)
                    app.Output.TE = TE_ReadRaw(app.Input.TEpar,0);

                    % Generate list or display warning message
                    if ~isempty(app.Output.TE.RunID.Sample) && ~isempty(app.Output.TE.RunID.Blank)
                        % If Sample and Blank ID were identified correctly proceed to generate list
                        set(app.Field_SampleList,'Visible','on')
                        set(app.Field_SampleList,'Enable','on')
                        set(app.Field_SampleList,'Editable','off')
                        set(app.Field_SampleList,'Value',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample])
                        set(app.Field_cpsSampleList,'Visible','on')
                        set(app.Field_cpsSampleList,'Enable','on')
                        set(app.Field_cpsSampleList,'Editable','off')
                        set(app.Field_cpsSampleList,'Value',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample])
                    else
                        % If incorrect ID inputs were provided no Blank/Sample ID will be detected (warning message)
                        if isempty(app.Output.TE.RunID.Blank)
                            warndlg('Procedural Blank ID not found in file. Check ID provided.','Incorrect input')
                            set(app.Field_SampleList,'Visible','off')
                            set(app.Label_SampleList,'Visible','on')
                            set(app.Label_SampleList,'Enable','on')
                        end
                        if isempty(app.Output.TE.RunID.Sample)
                            warndlg('Sample ID initials not found in file. Check sample initials provided.','Incorrect input parameters')
                            set(app.Field_SampleList,'Visible','off')
                            set(app.Label_SampleList,'Visible','on')
                            set(app.Label_SampleList,'Enable','on')
                        end
                        set(app.Field_SampleList,'Visible','off')
                        set(app.Label_SampleList,'Visible','on')
                        set(app.Label_SampleList,'Enable','on')
                    end
                else
                    warndlg('Please specify all input parameters before generating the sample list.','Missing input parameters')
                    set(app.Field_SampleList,'Visible','off')
                    set(app.Label_SampleList,'Visible','on')
                    set(app.Label_SampleList,'Enable','on')
                end
            end

            % CASE 2: ThU
            if State.ThU
                % Only attempt to generate sample list if all required inputs are provided
                if ThUallin
                    app.Output.ThU = ThU_Processor(app.Input.ThUpar,app.Input.SpikePar,app.Input.WtPar,0);

                    % If Sample ID was identified correctly proceed to generate list
                    if isstruct(app.Output.ThU)
                        ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
                        set(app.Field_SampleList,'Visible','on')
                        set(app.Field_SampleList,'Enable','on')
                        set(app.Field_SampleList,'Editable','off')
                        set(app.Field_SampleList,'Value',app.Output.ThU.SampleSeq(ThUSampleInd))
                        set(app.Field_cpsSampleList,'Visible','on')
                        set(app.Field_cpsSampleList,'Enable','on')
                        set(app.Field_cpsSampleList,'Editable','off')
                        set(app.Field_cpsSampleList,'Value',app.Output.ThU.SampleSeq(ThUSampleInd))
                    else
                        set(app.Field_SampleList,'Visible','off')
                    end
                else
                    warndlg('Please specify all input parameters before generating the sample list.','Missing input parameters')
                    set(app.Field_SampleList,'Visible','off')
                end
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Callback function: Button_Delete229, Button_Delete230, 
        % Button_Delete236, Button_EdtSve229, Button_EdtSve230, 
        % Button_EdtSve236, DropDown_229, DropDown_229, 
        % DropDown_230, DropDown_230, DropDown_236, DropDown_236
        function Spikes(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Spike control
            % This function collectively manages the user's interaction with the spike panel:
            % - Spike selection via dropdown menu
            % - Adding and deleting spikes
            % - Editing spike parameters
            % - Automatic spike selection from imported batch file
            %% -------------------------------------------------------------------------------

            % App directory
            SpikeSheetPath = [app.AppPath.folder,filesep,'misc',filesep,'spikes.xlsx'];

            % Determine spike function to run
            if event.Source ~= app.LoadBatchMenu && event.Source ~= app.ClearBatchMenu && event.Source ~= app.DeleteBatchMenu
                SpikeFun = event.Source.Tag(1:3);
                SpikeIso = event.Source.Tag(4:6);
                if strcmp(SpikeIso,'229')
                    SpikeIsoLong = 'Th229';
                elseif strcmp(SpikeIso,'230')
                    SpikeIsoLong = 'Th230';
                elseif strcmp(SpikeIso,'236')
                    SpikeIsoLong = 'U236';
                end
            elseif event.Source == app.LoadBatchMenu
                SpikeFun = 'loadbatch';
                SpikeIso = {'229','230','236'};
                SpikeIsoLong = {'Th229','Th230','U236'};
            elseif event.Source == app.ClearBatchMenu || event.Source == app.DeleteBatchMenu
                SpikeFun = 'clearbatch';
                SpikeIso = {'229','230','236'};
                SpikeIsoLong = {'Th229','Th230','U236'};
            end

            % Spike dropdown (get/select spikes)
            if strcmp(SpikeFun,'get')
                opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                spikes = readtable(SpikeSheetPath,opts);
                spikesInf = spikes{:,2:end};
                spikesInf(spikesInf == 65535) = Inf;
                spikes{:,2:end} = spikesInf;
                if strcmp(event.EventName,'DropDownOpening')
                    % Create spike list when dropdown is opened
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                elseif strcmp(event.EventName,'ValueChanged')
                    % Change to selected spike
                    value = app.(['DropDown_',SpikeIso]).Value;
                    if value > 0 && value < 999
                        set(app.(['Field_RY',SpikeIso]),'Value',spikes{value,2})
                        set(app.(['Field_CY',SpikeIso]),'Value',spikes{value,3})
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Value',spikes{value,4})
                        end
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        set(app.(['Button_Delete',SpikeIso]),'Enable','on')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                    elseif value == 0
                        set(app.(['Field_RY',SpikeIso]),'Value',Inf)
                        set(app.(['Field_CY',SpikeIso]),'Value',0)
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Value',Inf)
                            set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                        end
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    elseif value == 999
                        set(app.(['Field_CY',SpikeIso]),'Editable','on')
                        set(app.(['Field_RY',SpikeIso]),'Editable','on')
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Editable','on')
                            set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                            set(app.(['Field_RY',SpikeIso]),'Enable','on')
                        end
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                        newspikeID = inputdlg('Enter spike ID:');
                        if ~isempty(newspikeID)
                            set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};newspikeID;'Add'])
                            set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                            set(app.(['DropDown_',SpikeIso]),'Value',length(spikes{:,1})+1)
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                        else
                            app.(['DropDown_',SpikeIso]).Value = 0;
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Editable','off')
                            set(app.(['Field_RY',SpikeIso]),'Editable','off')
                            set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                            set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                            set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                        end
                    end
                end
            end

            % Edit spikes
            if strcmp(SpikeFun,'edt')
                set(app.(['Field_CY',SpikeIso]),'Editable','on')
                set(app.(['Field_RY',SpikeIso]),'Editable','on')
                if strcmp(SpikeIso,'236')
                    set(app.(['Field_RYb',SpikeIso]),'Editable','on')
                    set(app.(['Field_RY',SpikeIso]),'Enable','on')
                    set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                end
                set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
            end

            % Save spikes
            if strcmp(SpikeFun,'sve')
                value = app.(['DropDown_',SpikeIso]).Value;
                opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                spikes = readtable(SpikeSheetPath,opts);
                if value > length(spikes{:,1})
                    if strcmp(SpikeIso,'236')
                        spikes = [spikes;{0,0,0,0}];
                    else
                        spikes = [spikes;{0,0,0}];
                    end
                    spikes{end,1} = app.(['DropDown_',SpikeIso]).Items(app.(['DropDown_',SpikeIso]).Value+1);
                end
                spikes{value,2} = app.(['Field_RY',SpikeIso]).Value;
                spikes{value,3} = app.(['Field_CY',SpikeIso]).Value;
                if strcmp(SpikeIso,'236')
                    spikes{value,4} = app.(['Field_RYb',SpikeIso]).Value;
                end
                writetable(spikes,SpikeSheetPath,'Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                set(app.(['Field_CY',SpikeIso]),'Editable','off')
                set(app.(['Field_RY',SpikeIso]),'Editable','off')
                set(app.(['Field_RY',SpikeIso]),'Enable','off')
                if app.Button_TEtoggle.Value == 1
                    set(app.(['Field_RY',SpikeIso]),'Enable','on')
                end
                if strcmp(SpikeIso,'236')
                    set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                    set(app.(['Field_RYb',SpikeIso]),'Enable','off')
                    if app.Button_ThUtoggle.Value == 1
                        set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                    end
                end
                set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                set(app.(['Button_Delete',SpikeIso]),'Enable','on')
            end

            % Delete spikes
            if strcmp(SpikeFun,'dlt')
                value = app.(['DropDown_',SpikeIso]).Value;
                % Confirm dialog
                msg = ['Do you want to delete spike ''',app.(['DropDown_',SpikeIso]).Items{value+1},''''];
                title = 'Confirm Delete';
                selection = questdlg(msg,title,'Ok','Cancel','Ok');
                if strcmp(selection,'Ok')
                    opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                    spikes = readtable(SpikeSheetPath,opts);
                    spikes(value,:) = [];
                    writetable(spikes,SpikeSheetPath,'Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    app.(['DropDown_',SpikeIso]).Value = 0;
                    set(app.(['Field_RY',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso]),'Editable','off')
                    if strcmp(SpikeIso,'236')
                        set(app.(['Field_RYb',SpikeIso]),'Value',0)
                        set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                    end
                    set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                    set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                end
            end

            % Load spikes of imported batch
            if strcmp(SpikeFun,'loadbatch')
                % Create spike list
                for i = 1 : 3
                    opts = detectImportOptions(SpikeSheetPath','Sheet',SpikeIsoLong{i});
                    spikes = readtable(SpikeSheetPath,opts);
                    spikesInf = spikes{:,2:end};
                    spikesInf(spikesInf == 65535) = Inf;
                    spikes{:,2:end} = spikesInf;
                    set(app.(['DropDown_',SpikeIso{i}]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso{i}]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    if isfield(app.Input,'SpikeSel')
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',app.Input.SpikeSel(i))
                        if app.Input.SpikeSel(i) > 0 && app.Input.SpikeSel(i) < 999
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),2})
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),3})
                            if i == 3
                                set(app.(['Field_RYb',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),4})
                            end
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','on')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','on')
                        elseif app.Input.SpikeSel(i) == 0
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                            if i == 3
                                set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                            end
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                        end
                        set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    else
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                        set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                        if i == 3
                            set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                        end
                        set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                    end
                    set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    if i == 3
                        set(app.(['Field_RYb',SpikeIso{i}]),'Editable','off')
                    end
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                end
            end

            % Reset spikes for new batch
            if strcmp(SpikeFun,'clearbatch')
                % Create spike list
                for i = 1 : 3
                    set(app.(['DropDown_',SpikeIso{i}]),'Value',0)
                    set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                    set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                    set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    if i == 3
                        set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                        set(app.(['Field_RYb',SpikeIso{i}]),'Editable','off')
                    end
                    set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                end
            end

            % Bring ICPro to foreground
%             figure(app.ICProUIFigure)
        end

        % Selection changed function: CUSelTE, DataSelTE
        function SwitchResultTE(app, event)
            %---------------------------------------------------------------------------------
            % Callback: TE result selection
            % This function manages the selection and display of TE processing results.
            %% -------------------------------------------------------------------------------

            Selection = app.DataSelTE.SelectedObject.Tag;
            if strcmp(app.CUSelTE.SelectedObject.Tag,'Conc')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [app.Output.TE.C.DC.f_DC',app.Output.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.C.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Err')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [app.Output.TE.dC.DC.f_DC',app.Output.TE.dC.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.dC.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Errp')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [app.Output.TE.dC.DC.f_DC'./app.Output.TE.C.DC.f_DC',app.Output.TE.dC.(Selection).Sample'./app.Output.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.dC.(Selection).Sample'./app.Output.TE.C.(Selection).Sample';
                end
            end
            setappdata(app.ICProUIFigure,'ActiveResultTE',Selection);

            if (strcmp(Selection,'ID')) && app.Check_DC.Value == 1
                app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.IsotopesID];
            elseif (strcmp(Selection,'ID')) && app.Check_DC.Value == 0
                app.Table_TEresults.ColumnName = app.Output.TE.IsotopesID;
            elseif strcmp(Selection,'DC')
                app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.Isotopes];
            else
                app.Table_TEresults.ColumnName = app.Output.TE.Isotopes;
            end
        end

        % Callback function: CUSelThU, DataSelThU, ThUBCcheck
        function SwitchResultThU(app, event)
            %---------------------------------------------------------------------------------
            % Callback: ThU result selection
            % This function manages the selection and display of ThU processing results.
            %% -------------------------------------------------------------------------------

            Selection = app.DataSelThU.SelectedObject.Tag;
            ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
            if strcmp(app.CUSelThU.SelectedObject.Tag,'Conc')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)'];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.Ratios.NatU.R235_234_cMB;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234];
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC];
                    end
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Err')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)'];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.dRatios.NatU.R235_234_cMB;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230,app.Output.ThU.dID.U234];
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230BC,app.Output.ThU.dID.U234BC];
                    end
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Errp')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd)'./app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)'./app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)']*100;
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.dRatios.NatU.R235_234_cMB./app.Output.ThU.Ratios.NatU.R235_234_cMB*100;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230./app.Output.ThU.ID.Th230,app.Output.ThU.dID.U234./app.Output.ThU.ID.U234]*100;
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230BC./app.Output.ThU.ID.Th230BC,app.Output.ThU.dID.U234BC./app.Output.ThU.ID.U234BC]*100;
                    end
                end
            end

            if strcmp(Selection,'SampleMB')
                app.ThUBCcheck.Enable = 0;
                app.ThUBCcheck.Visible = 0;
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'NatUMB')
                app.ThUBCcheck.Enable = 0;
                app.ThUBCcheck.Visible = 0;
                app.Table_ThUresults.ColumnName = {'U235/U234'};
                app.Table_ThUresults.RowName = app.Output.ThU.NatUSeq;
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'ThUID')
                app.ThUBCcheck.Enable = 1;
                app.ThUBCcheck.Visible = 1;
                if app.ThUBCcheck.Value == 0
                    app.Table_ThUresults.ColumnName = {'Th230 (ID)','U234 (ID)'};
                else
                    app.Table_ThUresults.ColumnName = {'Th230 (ID), blank corrected','U234 (ID), blank corrected'};
                end
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.ConcThU.Text = '  Conc. (pg/g)';
                app.ErrThU.Text = '  Errors (pg/g)';
            end

            setappdata(app.ICProUIFigure,'ActiveResultThU',Selection);
        end

        % Menu selected function: BCexport, DCexport, IDLRplots, 
        % IDexport, NatURatioExp, OCexport, QCexport, RCexport, 
        % Rawexport, SCexport, SamRatioExp, TEThUsheet, TEsheet, 
        % ThUIDExp, ThUsheet
        function Export(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Export results
            % This function exports selected results as an excel spread sheet.
            %% -------------------------------------------------------------------------------

            % If available, get batch name for default file name (generic name in case of unsaved Batch
            if ~strcmp(app.BatchName,'unsaved batch')
                BatchControl(app,event)
                FileName = app.BatchName;
            else
                FileName = '[EnterBatchName]';
            end

            % File output directory, default: TE raw file directory,
            defaultpath = dir(app.Input.TEpar.RawPath);

            [FilePath1,FilePath2] = uiputfile('*.*','Select File',[defaultpath.folder,filesep,FileName]);
            if FilePath1 ~= 0
                if ~isfolder([FilePath2,'ICPro Results',filesep])
                    mkdir([FilePath2,'ICPro Results',filesep])
                end

                OutFile = [FilePath2,'ICPro Results',filesep,FilePath1,'.xlsx'];
                OutFig = [FilePath2,'ICPro Results',filesep,FilePath1,'.png'];
                if isfile(OutFile)
                    msg = 'Export file already exists and will be overwritten.';
                    title = 'Overwrite export file';
                    selection = questdlg(msg,title,'Save','Cancel','Save');
                    if strcmp(selection,'Save')
                        delete(OutFile)
                    end
                else
                    selection = 'Save';
                end

                % Export
                if strcmp(selection,'Save')
                    % Info sheet
                    Info = {'BATCH INFO SHEET';'';'GENERAL';'Processing date/time';'';'TE INFO';'Raw file location';'IDs (Sample/Blank/Spike/Rinse/QC)';'Corrections';'';'TH/U INFO';'Raw folder location';'# analysis blocks';'# samples/block';'IDs (Sample/proced. Blank/Blank/NatU)';'Sample sequence';'';'SPIKES';'229Th (ID/CY/RY)';'230Th (ID/CY/RY)';'236U (ID/CY/RYa/RYb)'};
                    Info(:,2) = repmat({'NA'},21,1);
                    Info([0,1,2,4,5,9,10,16,17]+1,2) = {''};
                    % BatchName
                    Info{1,2} = app.BatchName;
                    % Date/time
                    Info{4,2} = datestr(now,21);
                    if (event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet || event.Source == app.TEThUsheet)
                        % TE raw file location
                        Info{7,2} = app.Field_RawDataPath.Value;
                        % TE IDs
                        Info{8,2} = {' ',app.Field_SampleID.Value,app.Field_BlankID.Value,app.Field_SpikeID.Value,app.Field_RinseID.Value,app.Field_QCID.Value};
                        % TE corrections
                        YN = {'No','Yes'};
                        Info{9,2} = {' ',['RC - ',YN{app.Check_RC.Value+1}],[' QC - ',YN{app.Check_QC.Value+1}],[' SC - ',YN{app.Check_SC.Value+1}],[' OC - ',YN{app.Check_OC.Value+1}],[' BC - ',YN{app.Check_BC.Value+1}],[' DC - ',YN{app.Check_DC.Value+1}]};
                    end
                    if event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                        % ThU raw folder location
                        Info{12,2} = app.Field_RawFolder.Value;
                        % ThU # analysis blocks
                        Info{13,2} = num2str(app.Field_NBlocks.Value);
                        % ThU # samples per block
                        Info{14,2} = num2str(app.Field_NSamples.Value);
                        % ThU IDs
                        Info{15,2} = {' ',app.Field_SIDThU.Value,app.Field_BIDThU.Value,app.Field_ICPBlankID.Value,app.Field_ICPNatUID.Value};
                        % ThU sample sequence
                        Info{16,2} = [{' '},app.Output.ThU.Sequence'];
                        % Spike parameters
                        Info{19,2} = {' ',app.DropDown_229.Items{app.DropDown_229.Value==app.DropDown_229.ItemsData},num2str(app.Field_CY229.Value),num2str(app.Field_RY229.Value)};
                        Info{20,2} = {' ',app.DropDown_230.Items{app.DropDown_230.Value==app.DropDown_230.ItemsData},num2str(app.Field_CY230.Value),num2str(app.Field_RY230.Value)};
                        Info{21,2} = {' ',app.DropDown_236.Items{app.DropDown_236.Value==app.DropDown_236.ItemsData},num2str(app.Field_CY236.Value),num2str(app.Field_RY236.Value),num2str(app.Field_RYb236.Value)};
                    end

                    if event.Source ~= app.IDLRplots
                        OutTableInfo = array2table(Info);
                        writetable(OutTableInfo,OutFile,'WriteVariableNames',0,'Sheet','INFO','WriteMode','overwritesheet')
                    end

                    % Weight input sheet
                    % Replace empty columns with NaNs
                    if isnan(app.Input.WtPar.m_ThSC)
                        app.Input.WtPar.m_ThSC = NaN(1,numel(app.Input.WtPar.m_sed));
                    end
                    if isnan(app.Input.WtPar.m_USC)
                        app.Input.WtPar.m_USC = NaN(1,numel(app.Input.WtPar.m_sed));
                    end
                    if isnan(app.Input.WtPar.m_ThBC)
                        app.Input.WtPar.m_ThBC = NaN(1,numel(app.Input.WtPar.m_sed));
                    end
                    ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
                    if (event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet) && event.Source ~= app.IDLRplots
                        OutTableWt = array2table([app.Input.WtPar.m_sed;app.Input.WtPar.m_4ml;app.Input.WtPar.m_10ml;app.Input.WtPar.m_400ul;app.Input.WtPar.m_ThBC;app.Input.WtPar.m_UBC;app.Input.WtPar.m_ThSC;app.Input.WtPar.m_USC]',...
                            'RowNames',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample],'VariableNames',{'Sediment wt. [mg]','4ml wt. [mg]','10ml wt. [mg]','400ul wt. [mg]','Th229 (BC) wt. [mg]','U236 (BC) wt. [mg]','Th230 (SC) wt. [mg]','U236 (SC) wt. [mg]'});
                    elseif event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet
                        OutTableWt = array2table([app.Input.WtPar.m_sed;app.Input.WtPar.m_4ml;app.Input.WtPar.m_10ml;app.Input.WtPar.m_400ul;app.Input.WtPar.m_ThBC;app.Input.WtPar.m_UBC;app.Input.WtPar.m_ThSC;app.Input.WtPar.m_USC]',...
                            'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Sediment wt. [mg]','4ml wt. [mg]','10ml wt. [mg]','400ul wt. [mg]','Th229 (BC) wt. [mg]','U236 (BC) wt. [mg]','Th230 (SC) wt. [mg]','U236 (SC) wt. [mg]'});
                    elseif event.Source == app.TEThUsheet
                        OutTableWt = array2table([app.Input.WtPar.m_sed;app.Input.WtPar.m_4ml;app.Input.WtPar.m_10ml;app.Input.WtPar.m_400ul;app.Input.WtPar.m_ThBC;app.Input.WtPar.m_UBC;app.Input.WtPar.m_ThSC;app.Input.WtPar.m_USC]',...
                            'RowNames',app.SMatch,'VariableNames',{'Sediment wt. [mg]','4ml wt. [mg]','10ml wt. [mg]','400ul wt. [mg]','Th229 (BC) wt. [mg]','U236 (BC) wt. [mg]','Th230 (SC) wt. [mg]','U236 (SC) wt. [mg]'});
                    end

                    OutTableWt.Properties.DimensionNames{1} = 'Sample ID';
                    if event.Source ~= app.IDLRplots
                        writetable(OutTableWt,OutFile,'Sheet','Sample weights','WriteMode','overwritesheet','WriteRowNames',true)
                    end

                    % TE result sheets, concentration & errors (individual)
                    if (event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet || event.Source == app.TEThUsheet) && event.Source ~= app.IDLRplots
                        gettag = event.Source.Tag;
                        for i = 1 : length(app.Output.TE.IsotopesID)
                            app.Output.TE.IsotopesIDout{i} = [app.Output.TE.IsotopesID{i},' [ppm]'];
                            app.Output.TE.Isotopesout{i} = [app.Output.TE.Isotopes{i},' [ppm]'];
                        end
                        if strcmp(gettag,'ID') || event.Source == app.TEsheet || event.Source == app.TEThUsheet
                            OutTableC = array2table(app.Output.TE.C.ID.Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.IsotopesIDout);
                            OutTableE = array2table(app.Output.TE.dC.ID.Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.IsotopesIDout);
                            OutTableC.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableE.Properties.DimensionNames{1} = 'Sample ID';
                            writetable(OutTableC,OutFile,'Sheet','{TE} Concentrations (ID) [ug/g]','WriteMode','overwritesheet','WriteRowNames',true)
                            writetable(OutTableE,OutFile,'Sheet','{TE} Errors (ID) [ug/g]','WriteMode','overwritesheet','WriteRowNames',true)
                        else
                            OutTableC = array2table(app.Output.TE.C.(gettag).Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.Isotopesout);
                            OutTableE = array2table(app.Output.TE.dC.(gettag).Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.Isotopesout);
                            OutTableC.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableE.Properties.DimensionNames{1} = 'Sample ID';
                            writetable(OutTableC,OutFile,'Sheet',['{TE} Concentrations (',gettag,') [ug/g]'],'WriteMode','overwritesheet','WriteRowNames',true)
                            writetable(OutTableE,OutFile,'Sheet',['{TE} Errors (',gettag,') [ug/g]'],'WriteMode','overwritesheet','WriteRowNames',true)
                        end
                    end

                    % ID vs LR plots
                    if event.Source == app.IDLRplots
                        fig = figure('Visible','off');
                        fig.Position = [0 0 1000 500];
                        h1 = copyobj(app.ThIDFig,fig);
                        set(h1,'ActivePositionProperty','outerposition')
                        set(h1,'Units','normalized')
                        set(h1,'OuterPosition',[0 0 1 1])
                        set(h1,'Position',[0.025 0.05 .45 .9])
                        set(h1,'Visible','on')
                        for iC = 1 : length(h1.Children)
                            h1.Children(iC).Visible = 'on';
                        end
                        h2 = copyobj(app.UIDFig,fig);
                        set(h2,'ActivePositionProperty','outerposition')
                        set(h2,'Units','normalized')
                        set(h2,'OuterPosition',[0 0 1 1])
                        set(h2,'Position',[0.525 0.05 .45 .9])
                        set(h2,'Visible','on')
                        for iC = 1 : length(h2.Children)
                            h2.Children(iC).Visible = 'on';
                        end
                        saveas(fig,OutFig)
                        close(fig)
                    end

                    % Th/U result sheets, concentration & errors (individual)
                    if event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                        if event.Source == app.SamRatioExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                            OutTableCa = array2table([app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)'],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th229/Th230','U236/U234'});
                            OutTableEa = array2table([app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)'],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th229/Th230','U236/U234'});
                            OutTableCa.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEa.Properties.DimensionNames{1} = 'Sample ID';
                        end
                        if event.Source == app.NatURatioExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                            OutTableCb = array2table(app.Output.ThU.Ratios.NatU.R235_234_cMB,'RowNames',app.Output.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                            OutTableEb = array2table(app.Output.ThU.dRatios.NatU.R235_234_cMB,'RowNames',app.Output.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                            OutTableCb.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEb.Properties.DimensionNames{1} = 'Sample ID';
                        end
                        if event.Source == app.ThUIDExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                            if ~isempty(app.Field_BIDThU.Value)
                                OutTableCc = array2table([app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID), blank corrected [pg/g]','U234 (ID), blank corrected [pg/g]'});
                                OutTableEc = array2table([app.Output.ThU.dID.Th230BC,app.Output.ThU.dID.U234BC],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID), blank corrected [pg/g]','U234 (ID), blank corrected [pg/g]'});
                            else
                                OutTableCc = array2table([app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID) [pg/g]','U234 (ID) [pg/g]'});
                                OutTableEc = array2table([app.Output.ThU.dID.Th230,app.Output.ThU.dID.U234],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID) [pg/g]','U234 (ID) [pg/g]'});
                            end
                            OutTableCc.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEc.Properties.DimensionNames{1} = 'Sample ID';
                        end

                        if event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                            writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                            writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                            writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                            writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                            writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                        elseif event.Source == app.SamRatioExp
                            writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        elseif event.Source == app.NatURatioExp
                            writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        elseif event.Source == app.ThUIDExp
                            writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        end
                    end

                    % Spike recovery plot
                    %                     if event.Source == app.IDLRplots
                    %                         fig = figure('Visible','off');
                    %                         fig.Position = [0 0 1000 500];
                    %                         h1 = copyobj(app.RecoveryFig,fig);
                    %                         set(h1,'ActivePositionProperty','outerposition')
                    %                         set(h1,'Units','normalized')
                    %                         set(h1,'OuterPosition',[0 0 1 1])
                    %                         set(h1,'Position',[0.025 0.05 .45 .9])
                    %                         set(h1,'Visible','on')
                    %                         for iC = 1 : length(h1.Children)
                    %                             h1.Children(iC).Visible = 'on';
                    %                         end
                    %                         saveas(fig,OutFig)
                    %                         close(fig)
                    %                     end
                end
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Menu selected function: ClearBatchMenu, DeleteBatchMenu, 
        % LoadBatchMenu, SaveAsBatchMenu, SaveBatchMenu
        function BatchControl(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Batch control
            % This function collectively manages the handling of batch files:
            % - Saving input parameters to a new/existing batch file
            % - Importing parameters from a saved batch file
            % - Deleting batch files
            % - Clearing current input
            % Prior to clearing the input and importing a batch the user will be notified of
            % unsaved changes.
            %% -------------------------------------------------------------------------------

            % Get list of all batch files
            AllBatchFiles = dir([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,'*.mat']);
            BFList = cell(length(AllBatchFiles),1);
            for iF = 1 : length(AllBatchFiles)
                if ~contains(AllBatchFiles(iF).name,'~') || ~contains(AllBatchFiles(iF).name,'._')
                    BFList{iF} = AllBatchFiles(iF).name;
                end
            end
            BFList(contains(BFList,'~')) = [];

            % CASE 1: Load previously saved batch from batch file
            if event.Source == app.LoadBatchMenu
                msg = 'Unsaved changes to will be discarded. Load new batch and discard changes?';
                button = 'Proceed';
                discard = CheckForChanges(app,msg,button);
                if true(discard)
                    % Select batch file
                    indx = listdlg('SelectionMode','single','ListString',BFList,'ListSize',[160 100]);
                    if ~isempty(indx)
                        % Get batch file name from selection and load input parameters
                        BatchFile = BFList{indx};
                        load([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,BatchFile],'IN');
                        % Save batch name to app property 'BatchName' and rename app window accordingly
                        app.BatchName = BatchFile(1:end-4);
                        app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                        % Run function 'setInput'
                        setInput(app,event,IN)
                    end
                end
            end

            % CASE 2: Delete previously saved batch file
            if event.Source == app.DeleteBatchMenu
                % Select batch file
                indx = listdlg('SelectionMode','single','ListString',BFList,'ListSize',[160 100]);
                if ~isempty(indx)
                    % Get batch file name from selection
                    BatchFile = BFList{indx};
                    % Delete confirmation prompt
                    msg = ['Do you want to delete batch input file ''',BatchFile(1:end-4),'''?'];
                    title = 'Delete batch file';
                    selection = questdlg(msg,title,'Delete','Cancel','Delete');
                    if strcmp(selection,'Delete') % skip if selection was cancelled
                        % Delete file
                        delete([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,BatchFile]);
                        if strcmp(app.BatchName,BatchFile(1:end-4))
                            % Rename app window to 'unsaved batch'
                            app.ICProUIFigure.Name = 'ICPro - unnamed batch';
                            % Run function 'resetInput'
                            resetInput(app,event)
                        end
                    end
                end
            end

            % CASE 3: Clear current input and reset input window
            if event.Source == app.ClearBatchMenu
                msg = 'Unsaved changes will be discarded. Clear window and discard changes?';
                button = 'Proceed';
                discard = CheckForChanges(app,msg,button);
                if true(discard)
                    % Rename app window to 'unsaved batch'
                    app.ICProUIFigure.Name = 'ICPro - unnamed batch';
                    % Run function 'resetInput'
                    resetInput(app,event)
                end
            end

            % CASE 4: Save as new file (in case of 'Save as...' OR unsaved batch)
            unsaved = event.Source == app.SaveBatchMenu && strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            if event.Source == app.SaveAsBatchMenu || unsaved
                % Get all input data, parameters and selections
                IN = getInput(app);

                IN.BatchName = inputdlg('Specify batch name:');
                if ~isempty(IN.BatchName)
                    app.BatchName = IN.BatchName{1};
                    BatchExist = zeros(numel(AllBatchFiles),1);
                    for iB = 1 : numel(AllBatchFiles)
                        BatchExist(iB) =  strcmp([IN.BatchName{1},'.mat'],AllBatchFiles(iB).name);
                    end
                    if sum(BatchExist) == 1
                        msg = 'Batch file already exists and will be overwritten.';
                        title = 'Overwrite batch file';
                        selection = questdlg(msg,title,'Save','Cancel','Save');
                        if strcmp(selection,'Save')
                            save([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,IN.BatchName{1},'.mat'],'IN');
                            app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                        end
                    else
                        save([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,IN.BatchName{1},'.mat'],'IN');
                        app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                    end
                end
                clearvars('IN')
            end

            % CASE 5: Save to previously saved batch file (in case of 'Save' AND previously saved batch file)
            presaved = event.Source == app.SaveBatchMenu && ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            export = (event.Source.Parent == app.ExportMenu || event.Source.Parent == app.TEIndRes || event.Source.Parent == app.ThUIndRes) && ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            if presaved || export
                % Get all input data, parameters and selections
                IN = getInput(app);
                save([app.AppPath.folder,filesep,'misc',filesep,'Batch Input Files',filesep,IN.BatchName{1},'.mat'],'IN');
                app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                clearvars('IN')
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Close request function: ICProUIFigure
        function CloseApp(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Quitting ICPro
            % This function quits ICPro. Prior to quitting the user will be notified of
            % unsaved changes.
            %% -------------------------------------------------------------------------------

            msg = 'Unsaved changes will be discarded. Quit and discard changes?';
            button = 'Quit';
            discard = CheckForChanges(app,msg,button);
            if true(discard)
                delete(app)
            else
                % Bring ICPro to foreground
                figure(app.ICProUIFigure)
            end
        end

        % Menu selected function: AboutMenu, GuideMenu
        function ShowInfo(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Show ICPro info and guide
            % This function opens an info window or a user guide.
            %% -------------------------------------------------------------------------------

            if event.Source == app.AboutMenu
                d = dialog('Position',[500 500 250 350],'Name','About ICPro');

                axes('Parent',d,...
                    'Units','normalized',...
                    'Position',[.1 .6 .8 .3],...
                    'Position',[0 .55 1 .5],...
                    'XColor','none',...
                    'YColor','none');
                [icon,~,alpha] = imread([app.AppPath.folder,filesep,'misc',filesep,'icons',filesep,'appicon_square.png']);
                f = imshow(icon(:,:,1:3),'InitialMagnification',20);
                set(f, 'AlphaData', alpha);

                uicontrol('Parent',d,...
                    'Style','text',...
                    'Units','normalized',...
                    'Position',[.1 .3 .8 .2],...
                    'FontName','Avenir',...
                    'FontWeight','bold',...
                    'FontSize',24,...
                    'String','ICPro 2.1');

                uicontrol('Parent',d,...
                    'Style','text',...
                    'Units','normalized',...
                    'Position',[.1 .15 .8 .25],...
                    'FontName','Avenir',...
                    'FontWeight','bold',...
                    'FontSize',14,...
                    'String',{[char(hex2dec('A9')),' Jake Weis, ',datestr(today,3),' ',datestr(today,10)];' ';'https://github.com/JakeWeis';'jakob.weis@utas.edu.au'});

                waitfor(d)
                % Bring ICPro to foreground
                figure(app.ICProUIFigure)
            elseif event.Source == app.GuideMenu
                open([app.AppPath.folder,filesep,'misc',filesep,'info',filesep,'ICPro - User Guide.pdf'])
            elseif event.Source == app.TestBatchMenu
                BatchControl(app,event)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICProUIFigure = uifigure('Visible', 'off');
            app.ICProUIFigure.Name = 'ICPro - unnamed batch';
            app.ICProUIFigure.Icon = 'appicon_square.png';
            app.ICProUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.ICProMenu = uimenu(app.ICProUIFigure);
            app.ICProMenu.Text = 'ICPro';
            app.AboutMenu = uimenu(app.ICProMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowInfo, true);
            app.AboutMenu.Text = 'About...';
            app.PreferencesMenu = uimenu(app.ICProMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.GuideMenu = uimenu(app.ICProMenu);
            app.GuideMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowInfo, true);
            app.GuideMenu.Text = 'Guide';
            app.BatchMenu = uimenu(app.ICProUIFigure);
            app.BatchMenu.Text = 'Batch';
            app.SaveBatchMenu = uimenu(app.BatchMenu);
            app.SaveBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.SaveBatchMenu.Tooltip = {'Saves the current workspace to a batch file, which can later be imported.'};
            app.SaveBatchMenu.Accelerator = 'S';
            app.SaveBatchMenu.Text = 'Save';
            app.SaveBatchMenu.Tag = 'SaveBatch';
            app.SaveAsBatchMenu = uimenu(app.BatchMenu);
            app.SaveAsBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.SaveAsBatchMenu.Accelerator = 'N';
            app.SaveAsBatchMenu.Text = 'Save as...';
            app.SaveAsBatchMenu.Tag = 'SaveBatch';
            app.LoadBatchMenu = uimenu(app.BatchMenu);
            app.LoadBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.LoadBatchMenu.Tooltip = {'Loads a previously saved Batch.'};
            app.LoadBatchMenu.Accelerator = 'I';
            app.LoadBatchMenu.Text = 'Import';
            app.DeleteBatchMenu = uimenu(app.BatchMenu);
            app.DeleteBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.DeleteBatchMenu.Tooltip = {'Deletes a previously saved batch file.'};
            app.DeleteBatchMenu.Accelerator = 'D';
            app.DeleteBatchMenu.Text = 'Delete';
            app.ClearBatchMenu = uimenu(app.BatchMenu);
            app.ClearBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.ClearBatchMenu.Tooltip = {'Clears the current workspace and resets it to initial settings.'};
            app.ClearBatchMenu.Accelerator = 'X';
            app.ClearBatchMenu.Text = 'Clear';
            app.ExportMenu = uimenu(app.ICProUIFigure);
            app.ExportMenu.Tooltip = {'Export options'};
            app.ExportMenu.Text = 'Export';
            app.IndResExp = uimenu(app.ExportMenu);
            app.IndResExp.Tooltip = {''};
            app.IndResExp.Enable = 'off';
            app.IndResExp.Text = 'Individual results';
            app.TEIndRes = uimenu(app.IndResExp);
            app.TEIndRes.Tooltip = {'Exports concentrations and uncertainties'};
            app.TEIndRes.Enable = 'off';
            app.TEIndRes.Text = 'Trace elements';
            app.IDLRplots = uimenu(app.TEIndRes);
            app.IDLRplots.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDLRplots.Enable = 'off';
            app.IDLRplots.Text = 'ID vs LR plots';
            app.IDexport = uimenu(app.TEIndRes);
            app.IDexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDexport.Enable = 'off';
            app.IDexport.Text = 'Isotopic dilution';
            app.IDexport.Tag = 'ID';
            app.DCexport = uimenu(app.TEIndRes);
            app.DCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.DCexport.Enable = 'off';
            app.DCexport.Text = 'Dilution corrected';
            app.DCexport.Tag = 'DC';
            app.BCexport = uimenu(app.TEIndRes);
            app.BCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.BCexport.Enable = 'off';
            app.BCexport.Text = 'Blank corrected';
            app.BCexport.Tag = 'BC';
            app.OCexport = uimenu(app.TEIndRes);
            app.OCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.OCexport.Enable = 'off';
            app.OCexport.Text = 'Oxide corrected';
            app.OCexport.Tag = 'OC';
            app.SCexport = uimenu(app.TEIndRes);
            app.SCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SCexport.Enable = 'off';
            app.SCexport.Text = 'Spike corrected';
            app.SCexport.Tag = 'SC';
            app.QCexport = uimenu(app.TEIndRes);
            app.QCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.QCexport.Enable = 'off';
            app.QCexport.Text = 'QC corrected';
            app.QCexport.Tag = 'QC';
            app.RCexport = uimenu(app.TEIndRes);
            app.RCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.RCexport.Enable = 'off';
            app.RCexport.Text = 'Rinse corrected';
            app.RCexport.Tag = 'RC';
            app.Rawexport = uimenu(app.TEIndRes);
            app.Rawexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.Rawexport.Enable = 'off';
            app.Rawexport.Text = 'Raw data';
            app.Rawexport.Tag = 'Raw';
            app.ThUIndRes = uimenu(app.IndResExp);
            app.ThUIndRes.Tooltip = {'Exports ratios and uncertainties'};
            app.ThUIndRes.Enable = 'off';
            app.ThUIndRes.Text = 'Th/U';
            app.ThUIDExp = uimenu(app.ThUIndRes);
            app.ThUIDExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUIDExp.Tooltip = {'Th230 & U234'};
            app.ThUIDExp.Enable = 'off';
            app.ThUIDExp.Text = 'Isotopic Dilution';
            app.SamRatioExp = uimenu(app.ThUIndRes);
            app.SamRatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SamRatioExp.Tooltip = {'Th230/Th229'; 'U236/U234'};
            app.SamRatioExp.Enable = 'off';
            app.SamRatioExp.Text = 'Sample ratios';
            app.NatURatioExp = uimenu(app.ThUIndRes);
            app.NatURatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.NatURatioExp.Tooltip = {'U235/U234'};
            app.NatURatioExp.Enable = 'off';
            app.NatURatioExp.Text = 'NatU ratio';
            app.TEsheet = uimenu(app.ExportMenu);
            app.TEsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.TEsheet.Tooltip = {'- TE concentrations and uncertainties (with isotopic dilution)'; '- ID vs LR plots (Th232, U238)'};
            app.TEsheet.Enable = 'off';
            app.TEsheet.Accelerator = '1';
            app.TEsheet.Text = 'TE result sheet';
            app.ThUsheet = uimenu(app.ExportMenu);
            app.ThUsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUsheet.Tooltip = {'- Mass bias corrected sample ratios and uncertainties (Th229/Th230, U236/U234)'; '- Mass bias corrected NatU ratio and uncertainties (U236/U234)'};
            app.ThUsheet.Enable = 'off';
            app.ThUsheet.Accelerator = '2';
            app.ThUsheet.Text = 'Th/U result sheet';
            app.TEThUsheet = uimenu(app.ExportMenu);
            app.TEThUsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.TEThUsheet.Tooltip = {'- TE concentrations and uncertainties (with isotopic dilution)'; '- ID vs LR plots (Th232, U238)'; '- Mass bias corrected sample ratios and uncertainties (Th229/Th230, U236/U234)'; '- Mass bias corrected NatU ratio and uncertainties (U236/U234)'};
            app.TEThUsheet.Enable = 'off';
            app.TEThUsheet.Accelerator = '3';
            app.TEThUsheet.Text = 'TE+Th/U result sheet';
            app.Panel_AnalysisInfo = uipanel(app.ICProUIFigure);
            app.Panel_AnalysisInfo.Title = 'Analysis Information';
            app.Button_RawDataPath = uibutton(app.Panel_AnalysisInfo, 'push');
            app.Button_RawDataPath.ButtonPushedFcn = createCallbackFcn(app, @BrowseRawData, true);
            app.Button_RawDataPath.Text = '...';
            app.Field_RawDataPath = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Field_SampleID = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Field_BlankID = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Label_SampleID = uilabel(app.Panel_AnalysisInfo);
            app.Label_SampleID.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_AnalysisInfo);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.AnalysisSelection = uibuttongroup(app.Panel_AnalysisInfo);
            app.AnalysisSelection.SelectionChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Toggle_ThU = uitogglebutton(app.AnalysisSelection);
            app.Toggle_ThU.Tag = 'ThUtoggled';
            app.Toggle_ThU.Text = 'Th/U';
            app.Toggle_ThU.Value = true;
            app.Toggle_TE = uitogglebutton(app.AnalysisSelection);
            app.Toggle_TE.Tag = 'TEtoggled';
            app.Toggle_TE.Text = 'Trace Element';
            app.Panel_TECorr = uipanel(app.Panel_AnalysisInfo);
            app.Panel_TECorr.Title = 'Select TE corrections to apply';
            app.Check_RC = uicheckbox(app.Panel_TECorr);
            app.Check_RC.Tag = 'RC';
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.Panel_TECorr);
            app.Check_QC.Tag = 'QC';
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.Panel_TECorr);
            app.Check_SC.Tag = 'SC';
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.Panel_TECorr);
            app.Check_OC.Tag = 'OC';
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.Panel_TECorr);
            app.Check_BC.Tag = 'BC';
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.Panel_TECorr);
            app.Check_DC.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Check_DC.Tag = 'DC';
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.Panel_ThU = uipanel(app.ICProUIFigure);
            app.Panel_ThU.Enable = 'off';
            app.Panel_ThU.Title = 'Redundant fields';
            app.Panel_ThU.Visible = 'off';
            app.Field_NBlocks = uieditfield(app.Panel_ThU, 'text');
            app.Field_NBlocks.Visible = 'off';
            app.Field_NSamples = uieditfield(app.Panel_ThU, 'text');
            app.Field_NSamples.Visible = 'off';
            app.Label_NBlocks = uilabel(app.Panel_ThU);
            app.Label_NBlocks.Visible = 'off';
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_NSamples = uilabel(app.Panel_ThU);
            app.Label_NSamples.Visible = 'off';
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Field_ICPBlankID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPBlankID.Visible = 'off';
            app.Field_ICPBlankID.Value = 'Blank';
            app.Label_ICPBlankID = uilabel(app.Panel_ThU);
            app.Label_ICPBlankID.Visible = 'off';
            app.Label_ICPBlankID.Text = 'ICP Blank ID';
            app.Field_ICPNatUID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPNatUID.Visible = 'off';
            app.Field_ICPNatUID.Value = 'NatU';
            app.Label_ICPNatUID = uilabel(app.Panel_ThU);
            app.Label_ICPNatUID.Visible = 'off';
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Field_SpikeID = uieditfield(app.Panel_ThU, 'text');
            app.Field_SpikeID.Visible = 'off';
            app.Field_SpikeID.Value = 'spike';
            app.Label_SpikeID = uilabel(app.Panel_ThU);
            app.Label_SpikeID.Visible = 'off';
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.Field_RinseID = uieditfield(app.Panel_ThU, 'text');
            app.Field_RinseID.Visible = 'off';
            app.Field_RinseID.Value = 'Rinse';
            app.Label_RinseID = uilabel(app.Panel_ThU);
            app.Label_RinseID.Visible = 'off';
            app.Label_RinseID.Text = 'ICP Rinse ID';
            app.Field_QCID = uieditfield(app.Panel_ThU, 'text');
            app.Field_QCID.Visible = 'off';
            app.Field_QCID.Value = 'QC';
            app.Label_QCID = uilabel(app.Panel_ThU);
            app.Label_QCID.Visible = 'off';
            app.Label_QCID.Text = 'ICP QC ID';
            app.Button_TEtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_TEtoggle.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Button_TEtoggle.Enable = 'off';
            app.Button_TEtoggle.Visible = 'off';
            app.Button_TEtoggle.Tooltip = {''};
            app.Button_TEtoggle.Text = '';
            app.Button_ThUtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_ThUtoggle.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Button_ThUtoggle.Enable = 'off';
            app.Button_ThUtoggle.Visible = 'off';
            app.Button_ThUtoggle.Tooltip = {''};
            app.Button_ThUtoggle.Text = '';
            app.Button_Run = uibutton(app.ICProUIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.Button_Run.Text = '';
            app.SpikesPanel = uipanel(app.ICProUIFigure);
            app.SpikesPanel.Title = 'Spikes';
            app.Button_EdtSve229 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve229.Tag = 'edt229';
            app.Button_EdtSve229.Enable = 'off';
            app.Button_EdtSve229.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve229.Text = 'edit';
            app.Button_EdtSve230 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve230.Tag = 'edt230';
            app.Button_EdtSve230.Enable = 'off';
            app.Button_EdtSve230.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve230.Text = 'edit';
            app.Button_EdtSve236 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve236.Tag = 'edt236';
            app.Button_EdtSve236.Enable = 'off';
            app.Button_EdtSve236.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve236.Text = 'edit';
            app.Button_Delete229 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete229.Tag = 'dlt229';
            app.Button_Delete229.Enable = 'off';
            app.Button_Delete229.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete229.Text = 'delete';
            app.Button_Delete230 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete230.Tag = 'dlt230';
            app.Button_Delete230.Enable = 'off';
            app.Button_Delete230.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete230.Text = 'delete';
            app.Button_Delete236 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete236.Tag = 'dlt236';
            app.Button_Delete236.Enable = 'off';
            app.Button_Delete236.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete236.Text = 'delete';
            app.SpikeSelection = uipanel(app.SpikesPanel);
            app.SpikeSelection.Title = 'Spike selection';
            app.UspikeDropDownLabel = uilabel(app.SpikeSelection);
            app.UspikeDropDownLabel.Text = '236U spike';
            app.DropDown_236 = uidropdown(app.SpikeSelection);
            app.DropDown_236.Items = {'Select'};
            app.DropDown_236.ItemsData = 0;
            app.DropDown_236.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.Tag = 'get236';
            app.DropDown_236.Enable = 'off';
            app.DropDown_236.Value = 0;
            app.ThspikeDropDown_2Label = uilabel(app.SpikeSelection);
            app.ThspikeDropDown_2Label.Text = '230Th spike';
            app.DropDown_230 = uidropdown(app.SpikeSelection);
            app.DropDown_230.Items = {'Select'};
            app.DropDown_230.ItemsData = 0;
            app.DropDown_230.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.Tag = 'get230';
            app.DropDown_230.Enable = 'off';
            app.DropDown_230.Value = 0;
            app.ThspikeDropDownLabel = uilabel(app.SpikeSelection);
            app.ThspikeDropDownLabel.Text = '229Th spike';
            app.DropDown_229 = uidropdown(app.SpikeSelection);
            app.DropDown_229.Items = {'Select'};
            app.DropDown_229.ItemsData = 0;
            app.DropDown_229.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.Tag = 'get229';
            app.DropDown_229.Enable = 'off';
            app.DropDown_229.Value = 0;
            app.SpikeConcentration = uipanel(app.SpikesPanel);
            app.SpikeConcentration.Title = 'Concentration';
            app.Field_CY229 = uieditfield(app.SpikeConcentration, 'numeric');
            app.Field_CY229.ValueDisplayFormat = '%.2f';
            app.Field_CY229.Tag = 'Field_229ThCY';
            app.Field_CY229.Editable = 'off';
            app.Field_CY229.Enable = 'off';
            app.Field_CY229.Tooltip = {'229Th concentration in spike (pg/g)'};
            app.Field_CY230 = uieditfield(app.SpikeConcentration, 'numeric');
            app.Field_CY230.ValueDisplayFormat = '%.2f';
            app.Field_CY230.Tag = 'Field_230ThCY';
            app.Field_CY230.Editable = 'off';
            app.Field_CY230.Enable = 'off';
            app.Field_CY230.Tooltip = {'230Th concentration in spike (pg/g)'};
            app.Field_CY236 = uieditfield(app.SpikeConcentration, 'numeric');
            app.Field_CY236.ValueDisplayFormat = '%.2f';
            app.Field_CY236.Tag = 'Field_236UCY';
            app.Field_CY236.Editable = 'off';
            app.Field_CY236.Enable = 'off';
            app.Field_CY236.Tooltip = {'U236 concentration in spike (pg/g)'};
            app.IsoAmountRatio = uipanel(app.SpikesPanel);
            app.IsoAmountRatio.Title = 'Isotope amount ratio in spike';
            app.Field_RY229 = uieditfield(app.IsoAmountRatio, 'numeric');
            app.Field_RY229.ValueDisplayFormat = '%.2f';
            app.Field_RY229.Tag = 'Field_229ThRY';
            app.Field_RY229.Editable = 'off';
            app.Field_RY229.Enable = 'off';
            app.Field_RY229.Tooltip = {'Th229/230 ratio in spike (if unknown assume infinite, Inf)'};
            app.Field_RY229.Value = Inf;
            app.Field_RY230 = uieditfield(app.IsoAmountRatio, 'numeric');
            app.Field_RY230.ValueDisplayFormat = '%.2f';
            app.Field_RY230.Tag = 'Field_230ThRY';
            app.Field_RY230.Editable = 'off';
            app.Field_RY230.Enable = 'off';
            app.Field_RY230.Tooltip = {'Th230/229 ratio in spike (if unknown assume infinite, Inf)'};
            app.Field_RY230.Value = Inf;
            app.Field_RY236 = uieditfield(app.IsoAmountRatio, 'numeric');
            app.Field_RY236.ValueDisplayFormat = '%.2f';
            app.Field_RY236.Tag = '230';
            app.Field_RY236.Editable = 'off';
            app.Field_RY236.Enable = 'off';
            app.Field_RY236.Tooltip = {'U236/238 ratio in spike for isotopic dilution calculations of U238 (if unknown assume infinite, Inf)'};
            app.Field_RY236.Value = Inf;
            app.Field_RYb236 = uieditfield(app.IsoAmountRatio, 'numeric');
            app.Field_RYb236.ValueDisplayFormat = '%.2f';
            app.Field_RYb236.Tag = '229';
            app.Field_RYb236.Editable = 'off';
            app.Field_RYb236.Enable = 'off';
            app.Field_RYb236.Tooltip = {'Th236/234 ratio in spike for isotopic dilution calculations of U234 (if unknown assume infinite, Inf)'};
            app.Field_RYb236.Value = Inf;
            app.RYLabel4 = uilabel(app.IsoAmountRatio);
            app.RYLabel4.Text = '229/230';
            app.RYLabel3 = uilabel(app.IsoAmountRatio);
            app.RYLabel3.Text = '230/229';
            app.RYLabel2 = uilabel(app.IsoAmountRatio);
            app.RYLabel2.Text = '236/238';
            app.RYLabel1 = uilabel(app.IsoAmountRatio);
            app.RYLabel1.Text = '236/234';
            app.Panel_InputOutput = uitabgroup(app.ICProUIFigure);
            app.TabGroup_IDinput = uitab(app.Panel_InputOutput);
            app.TabGroup_IDinput.Title = 'Isotopic Dilution Input';
            app.Tabs_IDinput = uitabgroup(app.TabGroup_IDinput);
            app.Tab_IDWeights = uitab(app.Tabs_IDinput);
            app.Tab_IDWeights.Title = 'Weights [mg]';
            app.Field_Sediment = uitextarea(app.Tab_IDWeights);
            app.Field_Sediment.Tag = 'Field_Sediment';
            app.Field_Sediment.Enable = 'off';
            app.Label_Sediment = uilabel(app.Tab_IDWeights);
            app.Label_Sediment.Text = 'Sediment';
            app.Field_4ml = uitextarea(app.Tab_IDWeights);
            app.Field_4ml.Tag = 'Field_4ml';
            app.Field_4ml.Enable = 'off';
            app.Field_10ml = uitextarea(app.Tab_IDWeights);
            app.Field_10ml.Tag = 'Field_10ml';
            app.Field_10ml.Enable = 'off';
            app.Field_400ul = uitextarea(app.Tab_IDWeights);
            app.Field_400ul.Tag = 'Field_400ul';
            app.Field_400ul.Enable = 'off';
            app.Field_ThBC = uitextarea(app.Tab_IDWeights);
            app.Field_ThBC.Tag = 'Field_ThBC';
            app.Field_ThBC.Enable = 'off';
            app.Label_4ml = uilabel(app.Tab_IDWeights);
            app.Label_4ml.Text = '4 mL';
            app.Label_10ml = uilabel(app.Tab_IDWeights);
            app.Label_10ml.Text = '10 mL';
            app.Label_400ul = uilabel(app.Tab_IDWeights);
            app.Label_400ul.Text = '400 μL';
            app.Label_ThBC = uilabel(app.Tab_IDWeights);
            app.Label_ThBC.Text = '229Th (BC)';
            app.Field_UBC = uitextarea(app.Tab_IDWeights);
            app.Field_UBC.Tag = 'Field_UBC';
            app.Field_UBC.Enable = 'off';
            app.Field_ThSC = uitextarea(app.Tab_IDWeights);
            app.Field_ThSC.Tag = 'Field_ThSC';
            app.Field_ThSC.Enable = 'off';
            app.Label_UBC = uilabel(app.Tab_IDWeights);
            app.Label_UBC.Text = '236U (BC)';
            app.Label_ThSC = uilabel(app.Tab_IDWeights);
            app.Label_ThSC.Text = '230Th (SC)';
            app.Field_USC = uitextarea(app.Tab_IDWeights);
            app.Field_USC.Tag = 'Field_USC';
            app.Field_USC.Enable = 'off';
            app.Label_USC = uilabel(app.Tab_IDWeights);
            app.Label_USC.Text = '236U (SC)';
            app.Field_SampleList = uitextarea(app.Tab_IDWeights);
            app.Field_SampleList.Tag = 'Field_SampleList';
            app.Field_SampleList.Editable = 'off';
            app.Field_SampleList.Visible = 'off';
            app.Label_SampleList = uibutton(app.Tab_IDWeights, 'push');
            app.Label_SampleList.ButtonPushedFcn = createCallbackFcn(app, @SampleList, true);
            app.Label_SampleList.Enable = 'off';
            app.Label_SampleList.Text = 'ID';
            app.Tab_RawIntensities = uitab(app.Tabs_IDinput);
            app.Tab_RawIntensities.Title = 'Th & U raw intensities [cps]';
            app.Field_cpsSampleList = uitextarea(app.Tab_RawIntensities);
            app.Field_cpsSampleList.Editable = 'off';
            app.Field_cpsSampleList.Visible = 'off';
            app.Label_cpsSampleList = uilabel(app.Tab_RawIntensities);
            app.Label_cpsSampleList.Text = 'ID';
            app.Field_cps230Th = uitextarea(app.Tab_RawIntensities);
            app.Field_cps230Th.Enable = 'off';
            app.Label_cps230Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th.Text = 'Th230';
            app.Field_cps232Th = uitextarea(app.Tab_RawIntensities);
            app.Field_cps232Th.Enable = 'off';
            app.Field_cps236U = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U.Enable = 'off';
            app.Field_cps238U = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U.Enable = 'off';
            app.Label_cps232Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th.Text = '232Th';
            app.Label_cps236U = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U.Text = '236U';
            app.Label_cps238U = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U.Text = '238U';
            app.Label_cps230Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th_R.Text = 'Th230 Rinse';
            app.Label_cps232Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th_R.Text = '232Th Rinse';
            app.Label_cps236U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U_R.Text = '236U Rinse';
            app.Label_cps238U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U_R.Text = '238U Rinse';
            app.Field_cps230Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps230Th_R.Enable = 'off';
            app.Field_cps232Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps232Th_R.Enable = 'off';
            app.Field_cps236U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U_R.Enable = 'off';
            app.Field_cps238U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U_R.Enable = 'off';
            app.Footnote2 = uilabel(app.Tab_RawIntensities);
            app.Footnote2.Text = 'Rinse raw intensities are required for rinse correction. If not specified, Th and U raw intensities will not be rinse corrected (leave fields empty). Enter concentrations of each rinse (usually 1a-e, 2a/b, 3a/b)';
            app.Tab_TEoutput = uitab(app.Panel_InputOutput);
            app.Tab_TEoutput.Title = 'Trace Element Output';
            app.TabGroup_TEoutput = uitabgroup(app.Tab_TEoutput);
            app.Tab_TEresults = uitab(app.TabGroup_TEoutput);
            app.Tab_TEresults.Title = 'Concentrations';
            app.DataSelTE = uibuttongroup(app.Tab_TEresults);
            app.DataSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.RawButton = uitogglebutton(app.DataSelTE);
            app.RawButton.Tag = 'Raw';
            app.RawButton.Enable = 'off';
            app.RawButton.Text = 'Raw data';
            app.RawButton.Value = true;
            app.RCButton = uitogglebutton(app.DataSelTE);
            app.RCButton.Tag = 'RC';
            app.RCButton.Enable = 'off';
            app.RCButton.Text = 'Rinse corrected';
            app.QCButton = uitogglebutton(app.DataSelTE);
            app.QCButton.Tag = 'QC';
            app.QCButton.Enable = 'off';
            app.QCButton.Text = 'QC corrected';
            app.SCButton = uitogglebutton(app.DataSelTE);
            app.SCButton.Tag = 'SC';
            app.SCButton.Enable = 'off';
            app.SCButton.Text = 'Spike corrected';
            app.OCButton = uitogglebutton(app.DataSelTE);
            app.OCButton.Tag = 'OC';
            app.OCButton.Enable = 'off';
            app.OCButton.Text = 'Oxide corrected';
            app.BCButton = uitogglebutton(app.DataSelTE);
            app.BCButton.Tag = 'BC';
            app.BCButton.Enable = 'off';
            app.BCButton.Text = 'Blank corrected';
            app.DCButton = uitogglebutton(app.DataSelTE);
            app.DCButton.Tag = 'DC';
            app.DCButton.Enable = 'off';
            app.DCButton.Text = 'Dilution corrected';
            app.IDButton = uitogglebutton(app.DataSelTE);
            app.IDButton.Tag = 'ID';
            app.IDButton.Enable = 'off';
            app.IDButton.Tooltip = {'Th232 and U238 concentrations determined via isotopic dilution'};
            app.IDButton.Text = 'Isotopic Dilution';
            app.CUSelTE = uibuttongroup(app.DataSelTE);
            app.CUSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.ConcTE = uiradiobutton(app.CUSelTE);
            app.ConcTE.Tag = 'Conc';
            app.ConcTE.Enable = 'off';
            app.ConcTE.Text = '  Conc. (μg/g)';
            app.ConcTE.Value = true;
            app.ErrTE = uiradiobutton(app.CUSelTE);
            app.ErrTE.Tag = 'Err';
            app.ErrTE.Enable = 'off';
            app.ErrTE.Text = '  STD (μg/g)';
            app.ErrpTE = uiradiobutton(app.CUSelTE);
            app.ErrpTE.Tag = 'Errp';
            app.ErrpTE.Enable = 'off';
            app.ErrpTE.Text = '  STD (%)';
            app.Table_TEresults = uitable(app.Tab_TEresults);
            app.Table_TEresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_TEresults.RowName = {};
            app.Tab_TEplot = uitab(app.TabGroup_TEoutput);
            app.Tab_TEplot.Title = 'ID vs LR plots';
            app.UIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.UIDFig, '238U, LR (ppm)')
            ylabel(app.UIDFig, '238U, ID (ppm)')
            zlabel(app.UIDFig, 'Z')
            app.ThIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.ThIDFig, '232Th, LR (ppm)')
            ylabel(app.ThIDFig, '232Th, ID (ppm)')
            zlabel(app.ThIDFig, 'Z')
            app.Tab_ThUoutput = uitab(app.Panel_InputOutput);
            app.Tab_ThUoutput.Title = 'Th/U Output';
            app.TabGroup_ThUoutput = uitabgroup(app.Tab_ThUoutput);
            app.Tab_ThUresults = uitab(app.TabGroup_ThUoutput);
            app.Tab_ThUresults.Title = 'Concentrations & Ratios';
            app.Table_ThUresults = uitable(app.Tab_ThUresults);
            app.Table_ThUresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ThUresults.RowName = {};
            app.DataSelThU = uibuttongroup(app.Tab_ThUresults);
            app.DataSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.RatioSButton = uitogglebutton(app.DataSelThU);
            app.RatioSButton.Tag = 'SampleMB';
            app.RatioSButton.Enable = 'off';
            app.RatioSButton.Text = 'Sample ratios';
            app.RatioSButton.Value = true;
            app.RatioNButton = uitogglebutton(app.DataSelThU);
            app.RatioNButton.Tag = 'NatUMB';
            app.RatioNButton.Enable = 'off';
            app.RatioNButton.Text = 'NatU ratio';
            app.ThUIDButton = uitogglebutton(app.DataSelThU);
            app.ThUIDButton.Tag = 'ThUID';
            app.ThUIDButton.Enable = 'off';
            app.ThUIDButton.Text = 'Th230 & U234 (ID)';
            app.CUSelThU = uibuttongroup(app.Tab_ThUresults);
            app.CUSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.ConcThU = uiradiobutton(app.CUSelThU);
            app.ConcThU.Tag = 'Conc';
            app.ConcThU.Enable = 'off';
            app.ConcThU.Text = '  Conc. (pg/g)';
            app.ConcThU.Value = true;
            app.ErrThU = uiradiobutton(app.CUSelThU);
            app.ErrThU.Tag = 'Err';
            app.ErrThU.Enable = 'off';
            app.ErrThU.Text = '  STD (pg/g)';
            app.ErrpThU = uiradiobutton(app.CUSelThU);
            app.ErrpThU.Tag = 'Errp';
            app.ErrpThU.Enable = 'off';
            app.ErrpThU.Text = '  STD (%)';
            app.ThUBCcheck = uicheckbox(app.Tab_ThUresults);
            app.ThUBCcheck.ValueChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.ThUBCcheck.Enable = 'off';
            app.ThUBCcheck.Text = 'Blank correction';
            app.ThUBCcheck.Value = true;
            app.SpikeRecoveryTab = uitab(app.TabGroup_ThUoutput);
            app.SpikeRecoveryTab.Title = 'Spike Recovery';
            app.RecoveryFig = uiaxes(app.SpikeRecoveryTab);
            ylabel(app.RecoveryFig, 'counts per second [cps]')
            zlabel(app.RecoveryFig, 'Z')
            app.Field_SIDThU = uieditfield(app.ICProUIFigure, 'text');
            app.Field_SIDThU.Enable = 'off';
            app.Field_SIDThU.Visible = 'off';
            app.Label_SIDThU = uilabel(app.ICProUIFigure);
            app.Label_SIDThU.Enable = 'off';
            app.Label_SIDThU.Visible = 'off';
            app.Label_SIDThU.Text = 'Sample ID initials';
            app.Button_RawFolder = uibutton(app.ICProUIFigure, 'push');
            app.Button_RawFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseRawData, true);
            app.Button_RawFolder.Enable = 'off';
            app.Button_RawFolder.Visible = 'off';
            app.Button_RawFolder.Text = '...';
            app.Field_RawFolder = uieditfield(app.ICProUIFigure, 'text');
            app.Field_RawFolder.Enable = 'off';
            app.Field_RawFolder.Visible = 'off';
            app.Field_BIDThU = uieditfield(app.ICProUIFigure, 'text');
            app.Field_BIDThU.Enable = 'off';
            app.Field_BIDThU.Visible = 'off';
            app.Label_BIDThU = uilabel(app.ICProUIFigure);
            app.Label_BIDThU.Enable = 'off';
            app.Label_BIDThU.Visible = 'off';
            app.Label_BIDThU.Text = 'Procedural blank ID';
            % Show the figure after all components are created
            app.ICProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICProUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Splash)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICProUIFigure)
        end
    end
end