classdef ICProPreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferenceWindow     matlab.ui.Figure
        Label_CSLdil         matlab.ui.control.Label
        Label_RinseCorr      matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
        Check_DC             matlab.ui.control.CheckBox
        Check_BC             matlab.ui.control.CheckBox
        Check_OC             matlab.ui.control.CheckBox
        Check_SC             matlab.ui.control.CheckBox
        Check_QC             matlab.ui.control.CheckBox
        Check_RC             matlab.ui.control.CheckBox
        Field_CSLdil         matlab.ui.control.EditField
        Field_QCID           matlab.ui.control.EditField
        Field_RinseID        matlab.ui.control.EditField
        Field_SpikeID        matlab.ui.control.EditField
        Field_NSamples       matlab.ui.control.EditField
        Field_NBlocks        matlab.ui.control.EditField
        RinseCorrection      matlab.ui.container.ButtonGroup
        Accurate             matlab.ui.control.RadioButton
        Simple               matlab.ui.control.RadioButton
        Field_ICPNatUID      matlab.ui.control.EditField
        Field_ICPBlankID     matlab.ui.control.EditField
        ThoriumUraniumLabel  matlab.ui.control.Label
        Label_ICPBlankID     matlab.ui.control.Label
        Label_ICPNatUID      matlab.ui.control.Label
        Label_NBlocks        matlab.ui.control.Label
        Label_NSamples       matlab.ui.control.Label
        TraceElementsLabel   matlab.ui.control.Label
        Label_SpikeID        matlab.ui.control.Label
        Label_RinseID        matlab.ui.control.Label
        Label_QCID           matlab.ui.control.Label
        Label_TEcorrections  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GetPrefs(app)
            ICProPath = dir([mfilename('fullpath'),'.mlapp']);
            load([ICProPath.folder,filesep,'Preferences.mat'],'Preferences')
            
            % Store Preferences in variable
            app.Field_ICPBlankID.Value = Preferences.ThU.BID;
            app.Field_ICPNatUID.Value = Preferences.ThU.NatUID;
            app.Simple.Value = Preferences.ThU.SimpleRinse;
            app.Field_NBlocks.Value = num2str(Preferences.ThU.NBlocks);
            app.Field_NSamples.Value = num2str(Preferences.ThU.NSamples);
            
            app.Field_SpikeID.Value = Preferences.TE.VID;
            app.Field_RinseID.Value = Preferences.TE.RID;
            app.Field_QCID.Value = Preferences.TE.QID;
            app.Field_CSLdil.Value = num2str(Preferences.TE.CSLdil);
            app.Check_RC.Value = Preferences.TE.Check_RC;
            app.Check_QC.Value = Preferences.TE.Check_QC;
            app.Check_SC.Value = Preferences.TE.Check_SC;
            app.Check_OC.Value = Preferences.TE.Check_OC;
            app.Check_BC.Value = Preferences.TE.Check_BC;
            app.Check_DC.Value = Preferences.TE.Check_DC;
            
        end

        % Button pushed function: SaveButton
        function SavePrefs(app, event)
            ICProPath = dir([mfilename('fullpath'),'.mlapp']);
            % Store Preferences in variable
            Preferences.ThU.BID = app.Field_ICPBlankID.Value;
            Preferences.ThU.NatUID = app.Field_ICPNatUID.Value;
            Preferences.ThU.SimpleRinse = app.Simple.Value;
            Preferences.ThU.NBlocks = str2double(app.Field_NBlocks.Value);
            Preferences.ThU.NSamples = str2double(app.Field_NSamples.Value);
            
            Preferences.TE.VID = app.Field_SpikeID.Value;
            Preferences.TE.RID = app.Field_RinseID.Value;
            Preferences.TE.QID = app.Field_QCID.Value;
            Preferences.TE.CSLdil = app.Field_CSLdil.Value;
            Preferences.TE.Check_RC = app.Check_RC.Value;
            Preferences.TE.Check_QC = app.Check_QC.Value;
            Preferences.TE.Check_SC = app.Check_SC.Value;
            Preferences.TE.Check_OC = app.Check_OC.Value;
            Preferences.TE.Check_BC = app.Check_BC.Value;
            Preferences.TE.Check_DC = app.Check_DC.Value;
            
            if ~(Preferences.ThU.NBlocks > 0 || Preferences.ThU.NBlocks > 0)
                warndlg('Please enter valid numbers of analysis blocks and samples per block')
            else
                save([ICProPath.folder,filesep,'Preferences.mat'],'Preferences')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreferenceWindow = uifigure('Visible', 'off');
            app.PreferenceWindow.Name = 'Preferences';
            app.Label_TEcorrections = uilabel(app.PreferenceWindow);
            app.Label_TEcorrections.Text = 'TE corrections';
            app.Label_QCID = uilabel(app.PreferenceWindow);
            app.Label_QCID.Text = 'ICP QC ID';
            app.Label_RinseID = uilabel(app.PreferenceWindow);
            app.Label_RinseID.Text = 'ICP Rinse ID';
            app.Label_SpikeID = uilabel(app.PreferenceWindow);
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.TraceElementsLabel = uilabel(app.PreferenceWindow);
            app.TraceElementsLabel.Text = 'Trace Elements';
            app.Label_NSamples = uilabel(app.PreferenceWindow);
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Label_NBlocks = uilabel(app.PreferenceWindow);
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_ICPNatUID = uilabel(app.PreferenceWindow);
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Label_ICPBlankID = uilabel(app.PreferenceWindow);
            app.Label_ICPBlankID.Text = 'ICP Blank ID';
            app.ThoriumUraniumLabel = uilabel(app.PreferenceWindow);
            app.ThoriumUraniumLabel.Text = 'Thorium & Uranium';
            app.Field_ICPBlankID = uieditfield(app.PreferenceWindow, 'text');
            app.Field_ICPBlankID.Value = 'Blank';
            app.Field_ICPNatUID = uieditfield(app.PreferenceWindow, 'text');
            app.Field_ICPNatUID.Value = 'NatU';
            app.RinseCorrection = uibuttongroup(app.PreferenceWindow);
            app.Simple = uiradiobutton(app.RinseCorrection);
            app.Simple.Text = 'Simple';
            app.Simple.Value = true;
            app.Accurate = uiradiobutton(app.RinseCorrection);
            app.Accurate.Text = 'Accurate';
            app.Field_NBlocks = uieditfield(app.PreferenceWindow, 'text');
            app.Field_NBlocks.Value = '0';
            app.Field_NSamples = uieditfield(app.PreferenceWindow, 'text');
            app.Field_NSamples.Value = '0';
            app.Field_SpikeID = uieditfield(app.PreferenceWindow, 'text');
            app.Field_SpikeID.Value = 'spike';
            app.Field_RinseID = uieditfield(app.PreferenceWindow, 'text');
            app.Field_RinseID.Value = 'Rinse';
            app.Field_QCID = uieditfield(app.PreferenceWindow, 'text');
            app.Field_QCID.Value = 'QC';
            app.Field_CSLdil = uieditfield(app.PreferenceWindow, 'text');
            app.Field_CSLdil.Value = '5';
            app.Check_RC = uicheckbox(app.PreferenceWindow);
            app.Check_RC.Tag = 'RC';
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.PreferenceWindow);
            app.Check_QC.Tag = 'QC';
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.PreferenceWindow);
            app.Check_SC.Tag = 'SC';
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.PreferenceWindow);
            app.Check_OC.Tag = 'OC';
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.PreferenceWindow);
            app.Check_BC.Tag = 'BC';
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.PreferenceWindow);
            app.Check_DC.Tag = 'DC';
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.SaveButton = uibutton(app.PreferenceWindow, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SavePrefs, true);
            app.SaveButton.Text = 'Save';
            app.Label_RinseCorr = uilabel(app.PreferenceWindow);
            app.Label_RinseCorr.Text = 'Rinse correction';
            app.Label_CSLdil = uilabel(app.PreferenceWindow);
            app.Label_CSLdil.Text = 'CSL dilution';
            % Show the figure after all components are created
            app.PreferenceWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICProPreferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreferenceWindow)

            % Execute the startup function
            runStartupFcn(app, @GetPrefs)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferenceWindow)
        end
    end
end